classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainPanel                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        DataPanel                       matlab.ui.container.Panel
        D_NextButton                    matlab.ui.control.Button
        D_AVSVehicleScenarioButtonGroup  matlab.ui.container.ButtonGroup
        D_AVSTownButton                 matlab.ui.control.RadioButton
        D_AVSHighwayButton              matlab.ui.control.RadioButton
        D_UAVSensorDataButtonGroup      matlab.ui.container.ButtonGroup
        D_UAVGyroscopeButton            matlab.ui.control.RadioButton
        D_UAVAccelerometerButton        matlab.ui.control.RadioButton
        D_SWaTSelectSensorDropDown      matlab.ui.control.DropDown
        D_SWaTSelectSensorLabel         matlab.ui.control.Label
        D_LoadDataButton                matlab.ui.control.Button
        D_MainButtonGroup               matlab.ui.container.ButtonGroup
        D_AvailableDatasetsDropDownLabel  matlab.ui.control.Label
        D_AvailableDatasetsDropDown     matlab.ui.control.DropDown
        D_ImportCustomDatasetLabel      matlab.ui.control.Label
        D_ImportCustomDatasetTextArea   matlab.ui.control.TextArea
        D_BrowseCustomDatasetButton     matlab.ui.control.Button
        D_AvailableDatasetsButton       matlab.ui.control.RadioButton
        D_CustomDatasetButton           matlab.ui.control.RadioButton
        D_RawDatasetPreviewUIAxes       matlab.ui.control.UIAxes
        PreprocessingTab                matlab.ui.container.Tab
        PreprocessingPanel              matlab.ui.container.Panel
        P_MethodButtonGroup             matlab.ui.container.ButtonGroup
        P_RescaleButton                 matlab.ui.control.RadioButton
        P_StandardizeButton             matlab.ui.control.RadioButton
        P_RawDataButton                 matlab.ui.control.RadioButton
        P_PreviousButton                matlab.ui.control.Button
        P_NextButton                    matlab.ui.control.Button
        P_PreprocessedDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        ModelTrainingTab                matlab.ui.container.Tab
        ModelTrainingPanel              matlab.ui.container.Panel
        MT_ViewNetworkButton            matlab.ui.control.Button
        MT_TrainAllNetworksButton       matlab.ui.control.Button
        MT_SaveNetworksButton           matlab.ui.control.Button
        MT_TrainingStatusInformationLamp  matlab.ui.control.Lamp
        TrainingStatusunfinishedLampLabel  matlab.ui.control.Label
        MT_TrainSelectedNetworksButton  matlab.ui.control.Button
        MT_PreviousButton               matlab.ui.control.Button
        MT_NetworkSelectionButtonGroup  matlab.ui.container.ButtonGroup
        MT_AddNetworkButton             matlab.ui.control.Button
        MT_NetworksLabel                matlab.ui.control.Label
        MT_NetworksListBox              matlab.ui.control.ListBox
        MT_PretrainedNetworkLoadButton  matlab.ui.control.Button
        MT_PretrainedNetworkLabel       matlab.ui.control.Label
        MT_PretrainedNetworkTextArea    matlab.ui.control.TextArea
        MT_PretrainedNetworkBrowseButton  matlab.ui.control.Button
        MT_PretrainedNetworkButton      matlab.ui.control.RadioButton
        MT_NewNetworksButton            matlab.ui.control.RadioButton
        MT_NextButton                   matlab.ui.control.Button
        MT_PreviewUIAxes                matlab.ui.control.UIAxes
        DetectionEvaluationTab          matlab.ui.container.Tab
        DetectionEvaluationPanel        matlab.ui.container.Panel
        FaultydataselectionPanel_SWaT   matlab.ui.container.Panel
        OnlinedetectionsimulationButton_2  matlab.ui.control.Button
        VisualizedataButton_6           matlab.ui.control.Button
        PredictdataButton_3             matlab.ui.control.Button
        DayListBox                      matlab.ui.control.ListBox
        DayListBoxLabel                 matlab.ui.control.Label
        FaultydataselectionPanel_UAVAcc  matlab.ui.container.Panel
        OnlinedetectionsimulationButton_3  matlab.ui.control.Button
        VisualizedataButton_7           matlab.ui.control.Button
        FaulttypeButtonGroup_4          matlab.ui.container.ButtonGroup
        StuckvalueButton_3              matlab.ui.control.ToggleButton
        NoiseButton_3                   matlab.ui.control.ToggleButton
        OffsetButton_4                  matlab.ui.control.ToggleButton
        PredictdataButton_2             matlab.ui.control.Button
        ScenarioListBox_2               matlab.ui.control.ListBox
        ScenarioListBox_2Label          matlab.ui.control.Label
        FaulttypeButtonGroup_2          matlab.ui.container.ButtonGroup
        StuckvalueButton_2              matlab.ui.control.ToggleButton
        PackagedropButton               matlab.ui.control.ToggleButton
        OffsetButton_2                  matlab.ui.control.ToggleButton
        FaultydataselectionPanel_AVS    matlab.ui.container.Panel
        OnlinedetectionsimulationButton  matlab.ui.control.Button
        PredictdataButton               matlab.ui.control.Button
        ScenarioListBox                 matlab.ui.control.ListBox
        ScenarioListBoxLabel            matlab.ui.control.Label
        FaultdurationDropDown           matlab.ui.control.DropDown
        FaultdurationDropDownLabel      matlab.ui.control.Label
        VisualizedataButton_5           matlab.ui.control.Button
        FaulttypeButtonGroup            matlab.ui.container.ButtonGroup
        StuckvalueButton                matlab.ui.control.ToggleButton
        NoiseButton                     matlab.ui.control.ToggleButton
        OffsetButton                    matlab.ui.control.ToggleButton
        UAVPreparationPanel             matlab.ui.container.Panel
        VisualizedataButton_4           matlab.ui.control.Button
        NextButton_6                    matlab.ui.control.Button
        RestartButton_4                 matlab.ui.control.Button
        SensordataselectionButtonGroup  matlab.ui.container.ButtonGroup
        GyroscopeButton                 matlab.ui.control.ToggleButton
        AccelerometerButton             matlab.ui.control.ToggleButton
        AVSPreparationPanel             matlab.ui.container.Panel
        VisualizedataButton_3           matlab.ui.control.Button
        NextButton_5                    matlab.ui.control.Button
        RestartButton_3                 matlab.ui.control.Button
        VehiculescenarioButtonGroup     matlab.ui.container.ButtonGroup
        TownButton                      matlab.ui.control.ToggleButton
        HighwayButton                   matlab.ui.control.ToggleButton
        NetworkOptionsPanel             matlab.ui.container.Panel
        RatioEditField                  matlab.ui.control.NumericEditField
        RatioEditField_2Label           matlab.ui.control.Label
        NextButton_4                    matlab.ui.control.Button
        NetworkdesciptionButton         matlab.ui.control.Button
        UsevaldataCheckBox              matlab.ui.control.CheckBox
        SavenettoworkspaceButton        matlab.ui.control.Button
        MinibatchsizeEditField          matlab.ui.control.NumericEditField
        MinibatchsizeEditFieldLabel     matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        FilterNumberEditField           matlab.ui.control.NumericEditField
        FilterNumberEditFieldLabel      matlab.ui.control.Label
        StepSizesEditField              matlab.ui.control.NumericEditField
        StepSizesEditFieldLabel         matlab.ui.control.Label
        WindowsizewEditField            matlab.ui.control.NumericEditField
        WindowsizewEditFieldLabel       matlab.ui.control.Label
        HiddenUnitsEditField            matlab.ui.control.NumericEditField
        HiddenUnitsEditFieldLabel       matlab.ui.control.Label
        NumberofneuronsEditField        matlab.ui.control.NumericEditField
        NumberofneuronsEditFieldLabel   matlab.ui.control.Label
        NetworkspecificityDropDown      matlab.ui.control.DropDown
        NetworkspecificityDropDownLabel  matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        RestartButton_2                 matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SWaTDataPanel                   matlab.ui.container.Panel
        SelectedSensorLamp              matlab.ui.control.Lamp
        SelectedSensorLampLabel         matlab.ui.control.Label
        VisualizedataButton_2           matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        SelectedSensorCheckBox          matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        PreprocessingPanel_Old          matlab.ui.container.Panel
        NetworkButtonGroup_2            matlab.ui.container.ButtonGroup
        ReconstructionButton            matlab.ui.control.RadioButton
        PredictiveButton                matlab.ui.control.RadioButton
        NextButton_2                    matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        PreprocessingmethodButtonGroup  matlab.ui.container.ButtonGroup
        RawdataButton                   matlab.ui.control.ToggleButton
        Rescale01Button                 matlab.ui.control.ToggleButton
        StandardizeButton               matlab.ui.control.ToggleButton
        DataPreparationPanel            matlab.ui.container.Panel
        NextButton                      matlab.ui.control.Button
        NetworkButtonGroup              matlab.ui.container.ButtonGroup
        TrainnewnetworkButton           matlab.ui.control.RadioButton
        PretrainedButton                matlab.ui.control.RadioButton
        SelectDatasetButtonGroup        matlab.ui.container.ButtonGroup
        AVSButton                       matlab.ui.control.ToggleButton
        UAVButton                       matlab.ui.control.ToggleButton
        SWaTButton                      matlab.ui.control.ToggleButton
        selectedModelsContextMenu       matlab.ui.container.ContextMenu
        ShowNetworkOptionsMenu          matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% Data
        faultFreeData % Fault free data
        dataProc % Preprocessed data
        trainingData % Training data
        dataXTrain
        dataYTrain
        XTrain
        YTrain
        XVal
        YVal
        AVSType
        %%
        
        %% Network
        NetworkDescApp % Network description app
        layers
        options
        netDescription
        %%
        
        %% Rescale
        mini
        maxi
        %%
        
        %% Standarize
        mu
        sig
        %%
        
        saveNetPopupWin
        staticThreshold % Static theshold
        trainPredict % trainDataPrediction
        anomValData
        pd % Prob distrib
        simApp % Simulink app

%*************************************************************************
        %% App Status Information        
        datasetType = 'None'; % Tracks the loaded dataset
        preprocessingMethod = 'None'; % Tracks the applied preprocessing method

        loadedTrainingData = false; % True once training data has been loaded
        finishedPreprocessingTrainingData = false; % True once training data has been preprocessed
        finishedTrainingModels = false; % True once a model / multiple models have been successfully trained
        %%


        %% Data
        rawTrainingData % Initial fault free raw data
        timestampsTrainingData % Timestamps of training data
        preprocessedTrainingData % Preprocessed fault free data
        rawTestingData % Initial faulty raw data
        processedTestingData % Preprocessed faulty data
        SWaTData
        %%

        %% Model
        selectedNetworks % Structured data of selected models and their hyperparameters
        trainedNetworks % Trained deep neural networks array
        %%
        
        %% Popup Apps
        modelSelectionApp % Popup for selecting the models
        %%
    end
    
    properties (Access = public)
%         SWaT = load(fullfile(pwd, "myApp_resources\SWaT\SWaT Data.mat"));
%         UAVAcc = load(fullfile(pwd, "myApp_resources\UAV\UAV Acc.mat"), "faultFreeAcc");
%         UAVAccFault = load(fullfile(pwd, "myApp_resources\UAV\faultyAcc.mat"));
%         UAVGyrFault = load(fullfile(pwd, "myApp_resources\UAV\faultyGyr.mat"));
%         UAVGyr = load(fullfile(pwd, "myApp_resources\UAV\UAV Gyr.mat"), "faultFreeGyr");
%         AVS = load(fullfile(pwd, "myApp_resources\AVS\AVS.mat"));
%         SWaTAttacks = load(fullfile(pwd, "myApp_resources\SWaT\attackList.mat"))
        net % Network
        netOptions % Network options
        dataTest % Test data for simulink
        labels % Labels for simulink
    end
    
    methods (Access = private)
        
        function resetApp(app)
            % Make current instance of app invisible
            app.UIFigure.Visible = 'off';
            % Delete old instance
            delete(app)
            % Open 2nd instance of app
            myApp();
        end
        
        function layers = createGraph(app)
            if strcmp(app.NetworkspecificityDropDown.Value, 'Basic')
                neurons = app.NumberofneuronsEditField.Value;
                layers = [sequenceInputLayer(1, "Name", "Input")
                    fullyConnectedLayer(neurons, "Name", " Encode: Fully Connected with " + num2str(neurons) + " neurons")
                    fullyConnectedLayer(floor(neurons/2), "Name", "Encode: Fully Connected with " + num2str(floor(neurons/2)) + " neurons")
                    fullyConnectedLayer(floor(floor(neurons/2)/2), "Name", "Encode: Fully Connected with " + num2str(floor(floor(neurons/2)/2)) + " neurons")
                    fullyConnectedLayer(floor(neurons/2), "Name", "Decode:Fully Connected with " + num2str(floor(neurons/2)) + " neurons")
                    fullyConnectedLayer(neurons, "Name", "Decode: Fully Connected with " + num2str(neurons) + " neurons")
                    fullyConnectedLayer(1, "Name", "Out")
                    regressionLayer("Name", "Output")];
                layers = layerGraph(layers);
            elseif strcmp(app.NetworkspecificityDropDown.Value, 'Hybrid (RNN-CNN)')
                layers = [...
                    % input
                    sequenceInputLayer([1 1 1],'Name','Input')
                    sequenceFoldingLayer('Name','Fold')
                    
                    % CNN
                    convolution2dLayer(5,app.FilterNumberEditField.Value,'Padding','same','WeightsInitializer','he','Name',"Conv1: filter size: 5, #filters: " + num2str(app.FilterNumberEditField.Value),'DilationFactor',1);
                    batchNormalizationLayer('Name','bn')
                    reluLayer('Name','ReLU')
                    convolution2dLayer(5,app.FilterNumberEditField.Value,'Padding','same','WeightsInitializer','he','Name',"Conv2: filter size: 5, #filters: " + num2str(app.FilterNumberEditField.Value),'DilationFactor',1);
                    reluLayer('Name','ReLU2')
                    averagePooling2dLayer(1,'Stride',5,'Name','Pooling')
                    
                    % Flatten
                    sequenceUnfoldingLayer('Name','Unfold')
                    flattenLayer('Name','Flatten')
                    
                    % RNN
                    lstmLayer(app.HiddenUnitsEditField.Value,'Name',"LSTM1: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                    dropoutLayer(0.25,'Name','Dropout')
                    lstmLayer(app.HiddenUnitsEditField.Value,'Name',"LSTM2: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                    
                    % Output
                    fullyConnectedLayer(1,'Name','Fully connected')
                    regressionLayer('Name','Output')    ];
                
                layers = layerGraph(layers);
                layers= connectLayers(layers,'Fold/miniBatchSize','Unfold/miniBatchSize');
            elseif strcmp(app.NetworkspecificityDropDown.Value, 'LSTM')
                layers = [
                    sequenceInputLayer(1, "Name", "Input")
                    lstmLayer(app.HiddenUnitsEditField.Value, "Name","LSTM1: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units")
                    dropoutLayer(0.3, "Name", "Dropout")
                    lstmLayer(app.HiddenUnitsEditField.Value, "Name", "LSTM2: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units")
                    fullyConnectedLayer(1,"Name", "Fully connected")
                    regressionLayer("Name", "Output")];
                layers = layerGraph(layers);
            else
                layers = [
                    sequenceInputLayer(1, "Name", "Input")
                    lstmLayer(app.HiddenUnitsEditField.Value, "Name","GRU1: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units")
                    dropoutLayer(0.3, "Name", "Dropout")
                    lstmLayer(app.HiddenUnitsEditField.Value, "Name", "GRU2: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units")
                    fullyConnectedLayer(1,"Name", "Fully connected")
                    regressionLayer("Name", "Output")];
                layers = layerGraph(layers);
            end
        end
        
        function oldPointer = beginTask(app, figure)
            oldPointer = get(app.UIFigure, 'pointer');
            set(app.UIFigure, 'pointer', 'watch');
            figure.Enable = 'off';
            drawnow;
        end
        
        function endTask(app, figure, oldPointer)
            set(app.UIFigure, 'pointer', oldPointer);
            figure.Enable = 'on';
        end
        
        function openAndResizeWindow(app, figure_out, figure_in)
            figure_out.Visible = 'off';
            figure_out.Enable = 'off';
            figure_in.Position(1) = figure_out.Position(1);
            figure_in.Position(2) = figure_out.Position(2);
            pos = figure_in.Position;
            app.UIFigure.Position(3)= pos(3);
            app.UIFigure.Position(4)= pos(4);
            movegui(app.UIFigure,"center")
            figure_in.Visible = 'on';
            figure_in.Enable = 'on';
            app.UIFigure.Visible = 'on';
        end
        
        function desc = generateDesc(app)
            if app.NetworkButtonGroup_2.SelectedObject == app.ReconstructionButton
                if strcmp(app.NetworkspecificityDropDown.Value, 'Basic')
                    desc = "Basic dimentionality reduction reconstructor with 5 fully connected hidden layers";
                elseif strcmp(app.NetworkspecificityDropDown.Value,'Hybrid (RNN-CNN)')
                    desc = "Hybrid CNN-LSTM reconstructive network";
                else
                    desc = "LSTM Autoencoder";
                end
            else
                if strcmp(app.NetworkspecificityDropDown.Value,'Hybrid (RNN-CNN)')
                    desc = "Hybrid CNN-LSTM predictive network";
                elseif strcmp(app.NetworkspecificityDropDown.Value,'GRU')
                    desc = "RNN predictive network with GRU layers";
                else
                    desc = "RNN predictive network with LSTM layers";
                end
            end
        end
        
        function [rescaledData, maxi, mini] = rescaleData(~,data)
            if iscell(data)
                maxi = max([data{:,1}]);
                mini = min([data{:,1}]);
                rescaledData = data;
                for i = 1:size(data,1)
                    rescaledData{i,1} = (data{i,1}-mini)/(maxi-mini);
                end
            else
                maxi = max(data);
                mini = min(data);
                rescaledData = (data-mini)/(maxi-mini);
            end
        end
        
        function trainLSTM_AE(app)
            if iscell(app.XTrain)
                outputmode = 'sequence';
                sizeOut = size(app.XTrain{1,1},1);
            else
                outputmode = 'last';
                sizeOut = 1;
            end
            layers = [
                sequenceInputLayer(size(app.XTrain{1,1},1))
                lstmLayer(app.HiddenUnitsEditField.Value)
                dropoutLayer(0.3)
                lstmLayer(app.HiddenUnitsEditField.Value, "OutputMode", outputmode)
                fullyConnectedLayer(sizeOut)
                regressionLayer]; %#ok<*ADPROPLC>
            if isempty(app.XVal)
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001);
            else
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001, ...
                    "ValidationData", {app.XVal, app.XVal}, ...
                    "ValidationFrequency", floor(size(app.XTrain,1)/(3*app.MinibatchsizeEditField.Value)));
            end
            [app.net, app.netOptions] = trainNetwork(app.XTrain, app.XTrain, layers, options);
        end
        
        
        function [XtrEnd,YtrEnd] = splitData(app, data)
            [m, ~] = size(data);
            if m > 1
                data = data';
            end
            XTrain = lagmatrix(data, 1:app.WindowsizewEditField.Value);
            
            XTrain = XTrain(app.WindowsizewEditField.Value+1:end,:)';
            YTrain = data(app.WindowsizewEditField.Value+1:end); %#ok<*ADPROP>
            
            app.StepSizesEditField.Value = 2;

            sizeMax = floor(size(YTrain,2)/app.StepSizesEditField.Value);
            
            
            XtrEnd = cell(size(YTrain,2)-app.StepSizesEditField.Value+1, 1);
            if app.StepSizesEditField.Value ~= 1
                YtrEnd = cell(size(YTrain,2)-app.StepSizesEditField.Value+1, 1);
                for i = 1:size(YTrain,2)-app.StepSizesEditField.Value+1
                    XtrEnd{i,1} = flip(XTrain(:,i));
                    YtrEnd{i,1} = YTrain(:,i:i+app.StepSizesEditField.Value-1)';
                end
            else
                YtrEnd = [];
                for i = 1:size(YTrain,2)-app.StepSizesEditField.Value+1
                    XtrEnd{i,1} = flip(XTrain(:,i));
                    YtrEnd(i,1) = YTrain(:,i:i+app.StepSizesEditField.Value-1)';
                end
            end
        end
        
        function splitTrainVal(app)
            if app.RatioEditField.Enable == 1
                P = app.RatioEditField.Value;
                m = size(app.dataXTrain,1);
                %                 idx = randperm(m);
                %                 app.XTrain = app.dataXTrain(idx(1:round(P*m)),:);
                %                 app.YTrain = app.dataYTrain(idx(1:round(P*m)),:);
                %                 app.XVal = app.dataXTrain(round(P*m)+1:end,:);
                %                 app.YVal = app.dataYTrain(round(P*m)+1:end,:);
                
                idx = randperm(m);
                if app.SelectDatasetButtonGroup.SelectedObject ~= app.SWaTButton
                    idx = randperm(m);
                    app.XTrain = app.dataXTrain(idx(1:round(P*m)),:);
                    app.YTrain = app.dataYTrain(idx(1:round(P*m)),:);
                    app.XVal = app.dataXTrain(round(P*m)+1:end,:);
                    app.YVal = app.dataYTrain(round(P*m)+1:end,:);
                else
                    l = round((1-P)*m);
                    app.XTrain = app.dataXTrain(1:end-l-1,:);
                    app.YTrain = app.dataYTrain(1:end-l-1,:);
                    app.XVal = app.dataXTrain(end-l:end,:);
                    app.YVal = app.dataYTrain(end-l:end,:);
                end
                
            else
                app.XTrain = app.dataXTrain; app.YTrain = app.dataYTrain;
            end
            
        end
        
        function d = saveNetPopup(app)
            d = dialog("Name", "Save Network", "Position", [61 55 230 100], "Visible", "off", "WindowStyle", "modal");
            moveToFigCenter(app, d)
            netName = uicontrol("Parent",d, ...
                "Style","edit", ...
                "Position",[111 64 100 22]);
            netNameTxt = uicontrol("Parent", d, ...
                "Style", "text", ...
                "Position",[13 64 83 22], ...
                "String", "Network name"); %#ok<*NASGU>
            checkBox = uicontrol("Parent",d, ...
                "Style","checkbox", ...
                "String", "Export net options", ...
                "Position", [13 43 119 22]);
            btn = uicontrol("Parent",d, ...
                "Style","pushbutton", ...
                "Position", [70 12 100 22], ...
                "String", "Save", ...
                "Callback", @btnPush);
            d.Visible = 'on';
            function btnPush(src, event) %#ok<*INUSD>
                try
                    assignin("base", netName.String, app.net)
                    if checkBox.Value == 1
                        assignin("base", netName.String+"_options", app.netOptions)
                    end
                    btn.String = "Saved!";
                catch ME
                    d.Visible = 'off';
                    message = sprintf("Invalid variable name: '"+netName.String+"'\nPlease enter a valid name");
                    uialert(app.UIFigure,message,'Error Message', "CloseFcn",@closeAlert);
                    
                end
                function closeAlert(src, evnt)
                    d.Visible = "on";
                end
            end
        end
        
        function moveToFigCenter(app, fig2)
            pos = app.UIFigure.Position;
            fig2.Position(1) = pos(1);
            fig2.Position(2) = pos(2);
            pos2 = fig2.Position;
            pos2(1) = pos2(1)+pos(3)/2-pos2(3)/2;
            pos2(2) = pos2(2)+pos(4)/2-pos2(4)/2;
            fig2.Position = pos2;
        end
        
        function [trainData,dataResp] = splitDataEnd(app)
            if isa(app.dataProc, 'double')
                [trainData, dataResp] = splitData(app, app.dataProc);
            else
                trainData = cell(0,1);
                dataResp = [];
                for i = 1:size(app.dataProc,1)
                    [A, B] = splitData(app, app.dataProc{i,1});
                    trainData = [trainData; A];
                    dataResp = [dataResp; B];
                end
            end
        end
        
        
        function trainShallow_AE(app)
            neurons = app.NumberofneuronsEditField.Value;
            layers = [sequenceInputLayer(size(app.XTrain{1,1},1), "Name", "Input")
                fullyConnectedLayer(neurons, "Name", " Encode: Fully Connected with " + num2str(neurons) + " neurons")
                fullyConnectedLayer(floor(neurons/2), "Name", "Encode: Fully Connected with " + num2str(floor(neurons/2)) + " neurons")
                fullyConnectedLayer(floor(floor(neurons/2)/2), "Name", "Encode: Fully Connected with " + num2str(floor(floor(neurons/2)/2)) + " neurons")
                fullyConnectedLayer(floor(neurons/2), "Name", "Decode:Fully Connected with " + num2str(floor(neurons/2)) + " neurons")
                fullyConnectedLayer(neurons, "Name", "Decode: Fully Connected with " + num2str(neurons) + " neurons")
                fullyConnectedLayer(size(app.XTrain{1,1},1), "Name", "Out")
                regressionLayer("Name", "Output")];
            if isempty(app.XVal)
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001);
            else
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001, ...
                    "ValidationData", {app.XVal, app.XVal}, ...
                    "ValidationFrequency", floor(size(app.XTrain,1)/(3*app.MinibatchsizeEditField.Value)));
            end
            [app.net, app.netOptions] = trainNetwork(app.XTrain, app.XTrain, layers, options);
        end
        
        function trainHy_AE(app)
            numFeatures = size(app.XTrain{1,1}); % it depends on the roll back window (No of features, one output)
            
            numResponses = numFeatures(1);
            FiltZise = 5;
            learningrate = 0.001;


            %Learning rate
            %     solver = "adam";% solver option
            if gpuDeviceCount>0
                mydevice = 'gpu';
            else
                mydevice = 'cpu';
            end
            if isempty(app.XVal)
                options = trainingOptions('adam', ...
                    'MaxEpochs',app.EpochsEditField.Value, ...
                    'GradientThreshold',1, ...
                    'InitialLearnRate',learningrate, ...
                    'LearnRateSchedule',"piecewise", ...
                    'LearnRateDropPeriod',96, ...
                    'LearnRateDropFactor',0.25, ...
                    'MiniBatchSize',app.MinibatchsizeEditField.Value,...
                    'Verbose',false, ...
                    'Shuffle',"every-epoch",...
                    'ExecutionEnvironment',mydevice,...
                    'Plots','training-progress');
            else
                options = trainingOptions('adam', ...
                    'MaxEpochs',app.EpochsEditField.Value, ...
                    'GradientThreshold',1, ...
                    'InitialLearnRate',learningrate, ...
                    'LearnRateSchedule',"piecewise", ...
                    'LearnRateDropPeriod',96, ...
                    'LearnRateDropFactor',0.25, ...
                    'MiniBatchSize',app.MinibatchsizeEditField.Value,...
                    'Verbose',false, ...
                    'Shuffle',"every-epoch",...
                    'ExecutionEnvironment',mydevice,...
                    'Plots','training-progress', ...
                    "ValidationData", {app.XVal, app.XVal}, ...
                    "ValidationFrequency", floor(size(app.XTrain,1)/(3*app.MinibatchsizeEditField.Value)));
            end
            layers = [...
                % input
                sequenceInputLayer([numFeatures 1],'Name','Input')
                sequenceFoldingLayer('Name','Fold')
                
                % CNN
                convolution2dLayer(5,app.FilterNumberEditField.Value,'Padding','same','WeightsInitializer','he','Name',"Conv1: filter size: 5, #filters: " + num2str(app.FilterNumberEditField.Value),'DilationFactor',1);
                batchNormalizationLayer('Name','bn')
                reluLayer('Name','elu')
                convolution2dLayer(5,app.FilterNumberEditField.Value,'Padding','same','WeightsInitializer','he','Name',"Conv2: filter size: 5, #filters: " + num2str(app.FilterNumberEditField.Value),'DilationFactor',1);
                reluLayer('Name','elu1')
                averagePooling2dLayer(1,'Stride',5,'Name','Pooling')
                
                % Flatten
                sequenceUnfoldingLayer('Name','Unfold')
                flattenLayer('Name','Flatten')
                
                % RNN
                lstmLayer(app.HiddenUnitsEditField.Value,'Name',"LSTM1: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                dropoutLayer(0.25,'Name','Dropout')
                lstmLayer(app.HiddenUnitsEditField.Value,'Name',"LSTM2: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                
                % output
                fullyConnectedLayer(numResponses,'Name','Fully connected')
                regressionLayer('Name','Output')    ];
            
            layers = layerGraph(layers);
            layers= connectLayers(layers,'Fold/miniBatchSize','Unfold/miniBatchSize');
            [app.net, app.netOptions] = trainNetwork(app.XTrain, app.XTrain, layers, options);
        end
        
        function trainLSTM(app)
            if strcmp(class(app.YTrain), 'cell')
                outputmode = 'sequence';
                sizeOut = size(app.YTrain{1,1},1);
            else
                outputmode = 'last';
                sizeOut = 1;
            end
            layers = [
                sequenceInputLayer(size(app.XTrain{1,1},1))
                lstmLayer(app.HiddenUnitsEditField.Value)
                dropoutLayer(0.3)
                lstmLayer(app.HiddenUnitsEditField.Value, "OutputMode", outputmode)
                fullyConnectedLayer(sizeOut)
                regressionLayer]; %#ok<*ADPROPLC>
            if isempty(app.XVal)
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001);
            else
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001, ...
                    "ValidationData", {app.XVal, app.YVal}, ...
                    "ValidationFrequency", floor(size(app.XTrain,1)/(3*app.MinibatchsizeEditField.Value)));
            end
            [app.net, app.netOptions] = trainNetwork(app.XTrain, app.YTrain, layers, options);
        end
        
        function trainRNN(app)
            if strcmp(class(app.YTrain), 'cell')
                outputmode = 'sequence';
                sizeOut = size(app.YTrain{1,1},1);
            else
                outputmode = 'last';
                sizeOut = 1;
            end
            layers = [
                sequenceInputLayer(size(app.XTrain{1,1},1))
                gruLayer(app.HiddenUnitsEditField.Value)
                dropoutLayer(0.3)
                gruLayer(app.HiddenUnitsEditField.Value, "OutputMode", outputmode)
                fullyConnectedLayer(sizeOut)
                regressionLayer]; %#ok<*ADPROPLC>
            if isempty(app.XVal)
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001);
            else
                options = trainingOptions("adam", ...
                    "Plots","training-progress", ...
                    "MaxEpochs", app.EpochsEditField.Value, ...
                    "MiniBatchSize", app.MinibatchsizeEditField.Value, ...
                    "InitialLearnRate", 0.001, ...
                    "ValidationData", {app.XVal, app.YVal}, ...
                    "ValidationFrequency", floor(size(app.XTrain,1)/(3*app.MinibatchsizeEditField.Value)));
            end
            [app.net, app.netOptions] = trainNetwork(app.XTrain, app.YTrain, layers, options);
        end
        
        function trainHy(app)
            numFeatures = size(app.XTrain{1});% it depends on the roll back window (No of features, one output)
            numResponses = 1;FiltZise = 5;
            learningrate = 0.001;
            %Learning rate
            %     solver = "adam";% solver option
            if gpuDeviceCount>0
                mydevice = 'gpu';
            else
                mydevice = 'cpu';
            end
            if isempty(app.XVal)
                options = trainingOptions('adam', ...
                    'MaxEpochs',app.EpochsEditField.Value, ...
                    'GradientThreshold',1, ...
                    'InitialLearnRate',learningrate, ...
                    'LearnRateSchedule',"piecewise", ...
                    'LearnRateDropPeriod',96, ...
                    'LearnRateDropFactor',0.25, ...
                    'MiniBatchSize',app.MinibatchsizeEditField.Value,...
                    'Verbose',false, ...
                    'Shuffle',"every-epoch",...
                    'ExecutionEnvironment',mydevice,...
                    'Plots','training-progress');
            else
                options = trainingOptions('adam', ...
                    'MaxEpochs',app.EpochsEditField.Value, ...
                    'GradientThreshold',1, ...
                    'InitialLearnRate',learningrate, ...
                    'LearnRateSchedule',"piecewise", ...
                    'LearnRateDropPeriod',96, ...
                    'LearnRateDropFactor',0.25, ...
                    'MiniBatchSize',app.MinibatchsizeEditField.Value,...
                    'Verbose',false, ...
                    'Shuffle',"every-epoch",...
                    'ExecutionEnvironment',mydevice,...
                    'Plots','training-progress', ...
                    "ValidationData", {app.XVal, app.YVal}, ...
                    "ValidationFrequency", floor(size(app.XTrain,1)/(3*app.MinibatchsizeEditField.Value)));
            end
            layers = [...
                % input
                sequenceInputLayer([numFeatures 1],'Name','Input')
                sequenceFoldingLayer('Name','Fold')
                
                % CNN
                convolution2dLayer(5,app.FilterNumberEditField.Value,'Padding','same','WeightsInitializer','he','Name',"Conv1: filter size: 5, #filters: " + num2str(app.FilterNumberEditField.Value),'DilationFactor',1);
                batchNormalizationLayer('Name','bn')
                reluLayer('Name','elu')
                convolution2dLayer(5,app.FilterNumberEditField.Value,'Padding','same','WeightsInitializer','he','Name',"Conv2: filter size: 5, #filters: " + num2str(app.FilterNumberEditField.Value),'DilationFactor',1);
                reluLayer('Name','elu1')
                averagePooling2dLayer(1,'Stride',5,'Name','Pooling')
                
                % Flatten
                sequenceUnfoldingLayer('Name','Unfold')
                flattenLayer('Name','Flatten')
                
                % RNN
                lstmLayer(app.HiddenUnitsEditField.Value,'Name',"LSTM1: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                dropoutLayer(0.25,'Name','Dropout')
                lstmLayer(app.HiddenUnitsEditField.Value,'Name',"LSTM2: " + num2str(app.HiddenUnitsEditField.Value) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He', "OutputMode", "last")
                
                % output
                fullyConnectedLayer(numResponses,'Name','Fully connected')
                regressionLayer('Name','Output')    ];
            
            layers = layerGraph(layers);
            layers= connectLayers(layers,'Fold/miniBatchSize','Unfold/miniBatchSize');
            [app.net, app.netOptions] = trainNetwork(app.XTrain, app.YTrain, layers, options);
        end
        
        function legendBDCB(app, ~,evnt, axes)
            % This function uses the postion data from where the legend was
            % clicked to toggle a line on/off
            
            % First, get all of the lines
            lines = axes.Children;
            
            % Next, create segments based off of the number of lines. Based on
            % where the user clicked in the legend, we will assign that click
            % location to a line. This call generates a grid of equally spaced
            % ranges, with the number of ranges equaling the number of lines
            click_locations = linspace(0,1,numel(lines)+1);
            
            % Get corresponding bin based on the 2nd value of
            % evnt.IntersectionPoint. This corresponds to the vertical distance
            % within the legend, which we can use to identify what line
            % (roughly) in the legend was clicked
            line_num = discretize(evnt.IntersectionPoint(2),click_locations);
            
            % Set the visiblility of the corresponding line 'off' or 'on'
            relevant_line = lines(line_num);
            if strcmp(relevant_line.Visible,'on')
                relevant_line.Visible = 'off';
            else
                relevant_line.Visible = 'on';
            end
            
        end
        
        function preprocData(app)
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.Rescale01Button
                    [app.dataProc, app.maxi, app.mini] = rescaleData(app,app.faultFreeData);
                case app.StandardizeButton
                    try
                        [app.dataProc, app.mu, app.sig] = zscore(app.faultFreeData);
                    catch ME % if multiple fault free data, combine them all and standarize whole data
                        [~, app.mu, app.sig] = zscore([app.faultFreeData{:,1}]);
                        app.dataProc = cell(max(size(app.faultFreeData)),1);
                        for i = 1:max(size(app.dataProc))
                            app.dataProc{i,1} = (app.faultFreeData{i,1}-app.mu)/app.sig;
                        end
                    end
                case app.RawdataButton
                    app.dataProc = app.faultFreeData;
            end
            a = app.dataProc;
        end
        
        function pred = reshapeAutoencPred(app, data)
            c = [];
            for i = 1:size(data,1)
                c(:,i) = data{i};
            end
            b = [];
            c = flip(c);
            for i = 1:size(c,2)-app.WindowsizewEditField.Value
                b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
            end
            pred = b;
        end
        
        function data = randomAnomData(app, anomData, ratio)
            [m, ~] = size(anomData);
            idx = randperm(m);
            data = anomData(idx(1:round(ratio*m)),:);
        end
        
        function threshold = findStaticThresh(app, resAnom, anomLabels)
            %             beta = 1;
            %             idx = [];
            %             if isempty(app.XVal)
            %                 threshMax = linspace(min(resAnom),max(resAnom), 200);
            %                 y = logical([]);
            %                 if size(anomLabels, 2) ~= 1
            %                     anomLabels = anomLabels';
            %                 end
            %                 for i = 1:200
            %                     y(:,i) = resAnom > threshMax(i);
            %                 end
            %                 if size(anomLabels) ~= size(y)
            %                     anomLabels = anomLabels';
            %                 end
            %                 precision = []; recall = []; Fscore = [];
            %                 for i = 1:200
            %                     k = confusionmat(logical(anomLabels), logical(y(:,i)));
            %                     if width(k) > 1
            %                         precision(i) = k(2,2)/(k(2,2)+k(1,2));
            %                         recall(i) = k(2,2)/(k(2,2)+k(2,1));
            %                         Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
            %                     end
            %                 end
            %                 MaxFScore = max(Fscore);
            %                 thrIdx = find(Fscore == MaxFScore);
            %                 clear thrMax
            %                 if size(thrIdx, 2) >1
            %                     p = thrIdx(1);
            %                     thrMax = threshMax(thrIdx(end));
            %                 else
            %                     p = thrIdx;
            %                 end
            %
            %                 threshold = threshMax(p);
            %             else
            %                 p = predict(app.net, app.XVal);
            %                 r = abs(p-app.YVal);
            %                 phat = mle(r);
            %                 app.mle_mu = phat(1);
            %                 app.mle_sig = phat(2);
            %                 resAnom = (resAnom-app.mle_mu)/app.mle_sig;
            %                 threshMax = linspace(min(resAnom),max(resAnom), 200);
            %                 y = logical([]);
            %                 if size(anomLabels, 2) ~= 1
            %                     anomLabels = anomLabels';
            %                 end
            %                 for i = 1:200
            %                     y(:,i) = resAnom > threshMax(i);
            %                 end
            %                 if size(anomLabels) ~= size(y)
            %                     anomLabels = anomLabels';
            %                 end
            %                 precision = []; recall = []; Fscore = [];
            %                 for i = 1:200
            %                     k = confusionmat(logical(anomLabels), logical(y(:,i)));
            %                     if width(k) > 1
            %                         precision(i) = k(2,2)/(k(2,2)+k(1,2));
            %                         recall(i) = k(2,2)/(k(2,2)+k(2,1));
            %                         Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
            %                     end
            %                 end
            %                 MaxFScore = max(Fscore);
            %                 thrIdx = find(Fscore == MaxFScore);
            %                 clear thrMax
            %                 if size(thrIdx, 2) >1
            %                     p = thrIdx(1);
            %                     thrMax = threshMax(thrIdx(end));
            %                 else
            %                     p = thrIdx;
            %                 end
            %
            %                 threshold = threshMax(p);
            %             end
            if isempty(app.XVal)
                threshold = compute_thresh(app, resAnom, anomLabels, 0, 200);
            else
                threshold = [];
                threshold(1) = compute_thresh(app, resAnom, anomLabels, 0, 200);
                valPred = predict(app.net, app.XVal);
                if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                    resVal = abs(valPred-app.YVal);
                else
                    valPred = reshapeAutoencPred(app, valPred);
                    assignin("base", "yval1", app.YVal)                    
                    assignin("base", "valPred", valPred)
                    app.YVal = app.YVal(1:end-app.WindowsizewEditField.Value);
                    if length(valPred)>length(app.YVal)
                        valPred = valPred(1:length(app.YVal));
                    end
                    assignin("base", "yval", app.YVal)
                    resVal = abs(valPred-app.YVal);
                end
                app.pd = fitdist(resVal, "Normal");
                threshold(2) = compute_thresh(app, resAnom, anomLabels, 1, 2000);
            end
        end
        
        function thr = compute_thresh(app, resAnom, anomLabels, param, length)
            beta = 1;
            idx = [];
            if param == 1
                resAnom2=resAnom;
                resAnom = pdf(app.pd, resAnom);
            end
            threshMax = sort(resAnom);
            %             threshMax = linspace(min(resAnom),max(resAnom), length);
            y = logical([]);
            if size(anomLabels, 2) ~= 1
                anomLabels = anomLabels';
            end
            %new
            length = max(size(threshMax));
            if param == 0
                for i = 1:length
                    y(:,i) = resAnom > threshMax(i);
                end
            else
                for i = 1:length
                    y(:,i) = resAnom < threshMax(i);
                end
            end
            if size(anomLabels) ~= size(y)
                anomLabels = anomLabels';
            end
            precision = []; recall = []; Fscore = [];
            for i = 1:length
                k = confusionmat(logical(anomLabels), logical(y(:,i)));
                if width(k) > 1
                    precision(i) = k(2,2)/(k(2,2)+k(1,2));
                    recall(i) = k(2,2)/(k(2,2)+k(2,1));
                    Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
                end
            end
            %end
            %             if param == 0
            %                 for i = 1:length
            %                     y(:,i) = resAnom > threshMax(i);
            %                 end
            %             else
            %                 for i = 1:length
            %                     y(:,i) = resAnom < threshMax(i);
            %                 end
            %             end
            %             if size(anomLabels) ~= size(y)
            %                 anomLabels = anomLabels';
            %             end
            %             precision = []; recall = []; Fscore = [];
            %             for i = 1:length
            %                 k = confusionmat(logical(anomLabels), logical(y(:,i)));
            %                 if width(k) > 1
            %                     precision(i) = k(2,2)/(k(2,2)+k(1,2));
            %                     recall(i) = k(2,2)/(k(2,2)+k(2,1));
            %                     Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
            %                 end
            %             end
            MaxFScore = max(Fscore);
            thrIdx = find(Fscore == MaxFScore);
            clear thrMax
            if size(thrIdx, 2) >1
                p = thrIdx(1);
            else
                p = thrIdx;
            end
            
            thr = threshMax(p);
            if param == 1
                thr = resAnom2(find(resAnom==thr, 1));
            end
        end
        
        function index = getIndexes(app, data)
            try
                shift = [0; data(1:end-1)];
            catch
                shift = [0 data(1:end-1)];
            end
            change = shift ~= data;
            begin = find(data & change);
            endI = find(change & shift) -1;
            if length(endI) == length(begin) -1
                try
                    endI = [endI length(data)];
                catch
                    endI = [endI; length(data)];
                end
            end
            [0 1 1 0];
            [0 0 1 1];
            if size(begin, 1) ~= 1
                begin = begin';
                endI = endI';
            end
            index = [begin; endI; endI; begin];
        end
        
        function patchAnomalies(app, axes, labels)
            trueIndices = getIndexes(app, labels);
            YLims = get(axes, 'YLim');
            trueLims = [];
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            patch(axes, trueIndices, trueLims, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
        end
        
        function downsampleData(app)
            for i = 1:20
                app.UAVAccFault.Offset{i,1} = downsample(app.UAVAccFault.Offset{i,1},16);
                app.UAVAccFault.Offset{i,2} = downsample(app.UAVAccFault.Offset{i,2},16);
                app.UAVAccFault.PackageDrop{i,1} = downsample(app.UAVAccFault.PackageDrop{i,1},8);
                app.UAVAccFault.PackageDrop{i,2} = downsample(app.UAVAccFault.PackageDrop{i,2},8);
                app.UAVAccFault.StuckAt{i,1} = downsample(app.UAVAccFault.StuckAt{i,1},8);
                app.UAVAccFault.StuckAt{i,2} = downsample(app.UAVAccFault.StuckAt{i,2},8);
            end
        end

        function oldPointer = startTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.DataPanel.Enable = 'off';
            app.PreprocessingPanel.Enable = 'off';
            app.ModelTrainingPanel.Enable = 'off';
            app.DetectionEvaluationPanel.Enable = 'off';
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function stopTask(app, oldPointer)
            app.DataPanel.Enable = 'on';
            if app.loadedTrainingData
                app.PreprocessingPanel.Enable = 'on';
            end
%             if app.finishedPreprocessingTrainingData
%                 app.ModelTrainingPanel.Enable = 'on';
%             end
            app.ModelTrainingPanel.Enable = 'on';
            if app.finishedTrainingModels
                app.DetectionEvaluationPanel.Enable = 'on';
            end
            set(app.UIFigure, 'pointer', oldPointer);
        end
        
        function showDataPreparationFor(app, dataset)
            % Hides every data preperation tab except the on for the
            % selected dataset. Updates the label for the selected dataset.
            switch dataset
                case 'SWaT'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'on';
                    app.D_SWaTSelectSensorDropDown.Visible = 'on';
                case 'UAV'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'on';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
                case 'AVS'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'on';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
                case 'Custom'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
                case 'None'
                    app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
                    app.D_UAVSensorDataButtonGroup.Visible = 'off';
                    app.D_SWaTSelectSensorLabel.Visible = 'off';
                    app.D_SWaTSelectSensorDropDown.Visible = 'off';
            end
        end
        
        function customDataTable = loadCustomDataset(~, file)
            customDataTable = 0;

            if ~isfile(file)
                f = errordlg('Custom dataset file doesn''t exist!', ...
                        'File Error');
                return
            end

            univariateColumnNames = ['timestamp', 'value', 'is_anomaly'];
            data = readtable(file);

            if length(data.Properties.VariableNames) < 4
                % Univariate time series
                for i = 1:length(data.Properties.VariableNames)
                    if ~ismember(data.Properties.VariableNames{i}, univariateColumnNames)
                        f = errordlg(['Wrong column names. Should be: timestamp, value, is_anomaly. ' ...
                            'Third column (is_anomaly) is optional for training data.'], ...
                            'Column Name Error');
                        return
                    end
                end
                customDataTable = data;
            else
                % Multivariate time series. Not yet supported!
                f = errordlg(['Multivariate timeseries currently not supported. ' ...
                    'Use univariate timeseries with columns: timestamp, value, is_anomaly. ' ...
                    'Third column (is_anomaly) is optional for training data.'], ...
                    'Timeseries Format Error');
            end
        end
        
        function togglePreprocessingTab(app, state)
            switch state
                case true
                    app.loadedTrainingData = true;
                    app.PreprocessingPanel.Enable = 'on';
                    app.D_NextButton.Enable = 'on';
                case false
                    app.loadedTrainingData = false;
                    app.PreprocessingPanel.Enable = 'off';
                    app.D_NextButton.Enable = 'off';
            end
        end

        function toggleModelTrainingTab(app, state)
            switch state
                case true
                    app.finishedPreprocessingTrainingData = true;
                    app.ModelTrainingPanel.Enable = 'on';
                    app.P_NextButton.Enable = 'on';
                case false
                    app.finishedPreprocessingTrainingData = false;
                    app.ModelTrainingPanel.Enable = 'off';
                    app.P_NextButton.Enable = 'off';
            end
        end

        function toggleDetectionEvalutaionTab(app, state)
            switch state
                case true
                    app.finishedTrainingModels = true;
                    app.DetectionEvaluationPanel.Enable = 'on';
                case false
                    app.finishedTrainingModels = false;
                    app.DetectionEvaluationPanel.Enable = 'off';
            end
        end
        
        function preprocessedData = preprocessData(~, data, method)
            switch method
                case 'Rescale'
                    preprocessedData = normalize(data, 'range', [0, 1]);
                case 'Standardize'
                    preprocessedData = normalize(data, 'zscore', 'std');
            end            
        end
        
        function graph = createModelGraphForPreview(~, options)
            switch options.modelType
                case 'Reconstructive'
                    switch options.model
                        case 'Basic'
                            neurons = options.neurons;
                            layers = [sequenceInputLayer(1, "Name", "Input")
                                fullyConnectedLayer(neurons, "Name", " Encode: Fully Connected with " + num2str(neurons) + " neurons")
                                fullyConnectedLayer(floor(neurons/2), "Name", "Encode: Fully Connected with " + num2str(floor(neurons/2)) + " neurons")
                                fullyConnectedLayer(floor(floor(neurons/2)/2), "Name", "Encode: Fully Connected with " + num2str(floor(floor(neurons/2)/2)) + " neurons")
                                fullyConnectedLayer(floor(neurons/2), "Name", "Decode:Fully Connected with " + num2str(floor(neurons/2)) + " neurons")
                                fullyConnectedLayer(neurons, "Name", "Decode: Fully Connected with " + num2str(neurons) + " neurons")
                                fullyConnectedLayer(1, "Name", "Out")
                                regressionLayer("Name", "Output")];
                            layers = layerGraph(layers);
                        case 'Hybrid CNN-LSTM'
                            layers = [...
                                % Input
                                sequenceInputLayer([1 1 1],'Name','Input')
                                sequenceFoldingLayer('Name','Fold')
                                
                                % CNN
                                convolution2dLayer(5,options.filter,'Padding','same','WeightsInitializer','he','Name',"Conv1: filter size: 5, #filters: " + num2str(options.filter),'DilationFactor',1);
                                batchNormalizationLayer('Name','bn')
                                reluLayer('Name','ReLU')
                                convolution2dLayer(5,options.filter,'Padding','same','WeightsInitializer','he','Name',"Conv2: filter size: 5, #filters: " + num2str(options.filter),'DilationFactor',1);
                                reluLayer('Name','ReLU2')
                                averagePooling2dLayer(1,'Stride',5,'Name','Pooling')
                                
                                % Flatten
                                sequenceUnfoldingLayer('Name','Unfold')
                                flattenLayer('Name','Flatten')
                                
                                % RNN
                                lstmLayer(options.hiddenUnits,'Name',"LSTM1: " + num2str(options.hiddenUnits) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                                dropoutLayer(0.25,'Name','Dropout')
                                lstmLayer(options.hiddenUnits,'Name',"LSTM2: " + num2str(options.hiddenUnits) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                                
                                % Output
                                fullyConnectedLayer(1,'Name','Fully connected')
                                regressionLayer('Name','Output')];
                            
                            layers = layerGraph(layers);
                            layers= connectLayers(layers,'Fold/miniBatchSize','Unfold/miniBatchSize');                      
                        case 'LSTM'
                            layers = [
                                sequenceInputLayer(1, "Name", "Input")
                                lstmLayer(options.hiddenUnits, "Name","LSTM1: " + num2str(options.hiddenUnits) + " hidden units")
                                dropoutLayer(0.3, "Name", "Dropout")
                                lstmLayer(options.hiddenUnits, "Name", "LSTM2: " + num2str(options.hiddenUnits) + " hidden units")
                                fullyConnectedLayer(1,"Name", "Fully connected")
                                regressionLayer("Name", "Output")];
                            layers = layerGraph(layers);
                    end
                case 'Predictive'
                    switch options.model
                        case 'LSTM'
                            layers = [
                                sequenceInputLayer(1, "Name", "Input")
                                lstmLayer(options.hiddenUnits, "Name","LSTM1: " + num2str(options.hiddenUnits) + " hidden units")
                                dropoutLayer(0.3, "Name", "Dropout")
                                lstmLayer(options.hiddenUnits, "Name", "LSTM2: " + num2str(options.hiddenUnits) + " hidden units")
                                fullyConnectedLayer(1,"Name", "Fully connected")
                                regressionLayer("Name", "Output")];
                            layers = layerGraph(layers);
                        case 'Hybrid CNN-LSTM'
                            layers = [...
                                % Input
                                sequenceInputLayer([1 1 1],'Name','Input')
                                sequenceFoldingLayer('Name','Fold')
                                
                                % CNN
                                convolution2dLayer(5,options.filter,'Padding','same','WeightsInitializer','he','Name',"Conv1: filter size: 5, #filters: " + num2str(options.filter),'DilationFactor',1);
                                batchNormalizationLayer('Name','bn')
                                reluLayer('Name','ReLU')
                                convolution2dLayer(5,options.filter,'Padding','same','WeightsInitializer','he','Name',"Conv2: filter size: 5, #filters: " + num2str(options.filter),'DilationFactor',1);
                                reluLayer('Name','ReLU2')
                                averagePooling2dLayer(1,'Stride',5,'Name','Pooling')
                                
                                % Flatten
                                sequenceUnfoldingLayer('Name','Unfold')
                                flattenLayer('Name','Flatten')
                                
                                % RNN
                                lstmLayer(options.hiddenUnits,'Name',"LSTM1: " + num2str(options.hiddenUnits) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                                dropoutLayer(0.25,'Name','Dropout')
                                lstmLayer(options.hiddenUnits,'Name',"LSTM2: " + num2str(options.hiddenUnits) + " hidden units",'RecurrentWeightsInitializer','He','InputWeightsInitializer','He')
                                
                                % Output
                                fullyConnectedLayer(1,'Name','Fully connected')
                                regressionLayer('Name','Output')];
                            
                            layers = layerGraph(layers);
                            layers= connectLayers(layers,'Fold/miniBatchSize','Unfold/miniBatchSize');     
                        case 'GRU'
                            layers = [
                                sequenceInputLayer(1, "Name", "Input")
                                lstmLayer(options.hiddenUnits, "Name","GRU1: " + num2str(options.hiddenUnits) + " hidden units")
                                dropoutLayer(0.3, "Name", "Dropout")
                                lstmLayer(options.hiddenUnits, "Name", "GRU2: " + num2str(options.hiddenUnits) + " hidden units")
                                fullyConnectedLayer(1,"Name", "Fully connected")
                                regressionLayer("Name", "Output")];
                            layers = layerGraph(layers);
                    end
            end

            a = layers.Connections;
            b = extractBefore(a.Source, '/');
            c = extractBefore(a.Destination, '/');
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end
        
        function label = getLabelForNetwork(app, model)
            label = strcat(model, '  (1)');
            itemCounter = 0;
            for i = 1:length(app.MT_NetworksListBox.Items)
                item = split(app.MT_NetworksListBox.Items(i), '  (');
                if strcmp(model, item{1})
                    itemCounter = itemCounter + 1;
                end
            end
            if itemCounter > 0
                label = sprintf('%s  (%d)', model, itemCounter + 1);
            end
        end

        function updateNetworkLabels(app)
            itemsLength = length(app.MT_NetworksListBox.Items);
            newItems = cell(itemsLength, 1);
            newItemsData = struct('model',{}, ...
                'modelType',{}, ...
                'neurons',{}, ...
                'hiddenUnits',{}, ...
                'filter',{}, ...
                'windowSize',{}, ...
                'stepSize',{}, ...
                'epochs',{}, ...
                'minibatchSize',{}, ...
                'trainValRatio',{}, ...
                'label',{});
            idx = 1;
            labelCounter = 0;
            updatedLabels = strings();

            for i = 1:itemsLength
                item = split(app.MT_NetworksListBox.Items(i), '  (');
                if ~ismember(item{1}, updatedLabels)
                    for j = 1:itemsLength
                        itemCmp = split(app.MT_NetworksListBox.Items(j), '  (');
                        if strcmp(item{1}, itemCmp{1})
                            newItems{idx} = sprintf('%s  (%d)', itemCmp{1}, labelCounter + 1);
                            newItemsData(idx) = app.MT_NetworksListBox.ItemsData(j);
                            newItemsData(idx).label = newItems(idx);
                            idx = idx + 1;
                            labelCounter = labelCounter + 1;
                        end
                    end
                    updatedLabels(end + 1) = item{1};
                    labelCounter = 0;
                end
            end
            app.MT_NetworksListBox.Items = newItems;
            app.MT_NetworksListBox.ItemsData = newItemsData;
        end
        
        function toggleTrainButtons(app, state)
            if state
                app.MT_TrainAllNetworksButton.Enable = 'on';
                app.MT_TrainSelectedNetworksButton.Enable = 'on';
            else
                app.MT_TrainAllNetworksButton.Enable = 'off';
                app.MT_TrainSelectedNetworksButton.Enable = 'off';
            end
        end
        
        function net = trainNetwork(app, options)
            app.prepareDataForTraining(app.preprocessedTrainingData, options.windowSize, options.stepSize);
            switch options.modelType
                case 'Reconstructive'

                    switch options.model
                        case 'Basic'
                        case 'Hybrid CNN-LSTM'
                    end
                case 'Predictive'
                    switch options.model
                        case 'LSTM'
                        case 'Hybrid CNN-LSTM'
                        case 'GRU'
                    end
            end
        end
        
        function [XTrain, YTrain] = prepareDataForTraining(app, data, windowSize, stepSize, getYTrain)
            [m, ~] = size(data);
            if m > 1
                data = data';
            end

            XTrainLag = lagmatrix(data, 1:windowSize);      
            XTrainAll = XTrainLag(windowSize+1:end,:)';
            
            numOfWindows = (size(data, 2) - windowSize - stepSize + 1) / stepSize;
            XTrain = cell(numOfWindows, 1);

            if getYTrain
                YTrain = data(windowSize+1:end);
            else
            end
            
            sizeMax = floor(size(YTrain,2)/app.StepSizesEditField.Value);
            
            
            XtrEnd = cell(size(YTrain,2)-app.StepSizesEditField.Value+1, 1);
            if app.StepSizesEditField.Value ~= 1
                YtrEnd = cell(size(YTrain,2)-app.StepSizesEditField.Value+1, 1);
                for i = 1:size(YTrain,2)-app.StepSizesEditField.Value+1
                    XtrEnd{i,1} = flip(XTrain(:,i));
                    YtrEnd{i,1} = YTrain(:,i:i+app.StepSizesEditField.Value-1)';
                end
            else
                YtrEnd = [];
                for i = 1:size(YTrain,2)-app.StepSizesEditField.Value+1
                    XtrEnd{i,1} = flip(XTrain(:,i));
                    YtrEnd(i,1) = YTrain(:,i:i+app.StepSizesEditField.Value-1)';
                end
            end
        end
    end

    methods (Access = public)
        
        function addNetwork(app, networkOptions)
            label = app.getLabelForNetwork(networkOptions.model);
            networkOptions.label = label;
            app.MT_NetworksListBox.Items = [app.MT_NetworksListBox.Items label];
            app.MT_NetworksListBox.ItemsData = [app.MT_NetworksListBox.ItemsData networkOptions];

            app.toggleTrainButtons(true)
        end

        function addNetworks(~, networks)
            for i = 0:length(networks)
                app.addNetwork(networks(i));
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.MT_PreviewUIAxes, 'off') % Turn off axes for network preview

            app.UIFigure.Visible = 'off';
%             % Set all secondary panels to invisible
%             app.NetworkOptionsPanel.Visible = 'off';
%             app.SWaTDataPanel.Visible = 'off';
%             %app.DataPreparationPanel.Visible = 'off';
%             app.PreprocessingPanel_Old.Visible = 'off';
%             app.UAVPreparationPanel.Visible = 'off';
%             app.AVSPreparationPanel.Visible = 'off';
%             app.MainPanel.Visible = 'off';

            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';

%             app.HiddenUnitsEditField.Visible = 0;
%             app.HiddenUnitsEditFieldLabel.Visible = 0;
%             app.FilterNumberEditField.Visible = 0;
%             app.FilterNumberEditFieldLabel.Visible = 0;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            oldP = beginTask(app, app.DataPreparationPanel);
            switch app.NetworkButtonGroup.SelectedObject
                case app.TrainnewnetworkButton
                    switch app.SelectDatasetButtonGroup.SelectedObject
                        case app.SWaTButton
                            app.faultFreeData = app.SWaT.SWaTDatasetNormalv0;
                            app.UITable.Data = app.faultFreeData;
                            app.UITable.ColumnName = app.faultFreeData.Properties.VariableNames;
                            s = uistyle('BackgroundColor','blue');
                            addStyle(app.UITable,s,'column',[3 20 29 30])
                            openAndResizeWindow(app, app.DataPreparationPanel, app.SWaTDataPanel);
                        case app.AVSButton
                            openAndResizeWindow(app, app.DataPreparationPanel, app.AVSPreparationPanel);
                        case app.UAVButton
                            openAndResizeWindow(app, app.DataPreparationPanel, app.UAVPreparationPanel);
                            % exportapp(app.UIFigure, 'C:\Users\Alia\Desktop\report\Figures\APP\UAV.pdf')
                    end
                case app.PretrainedButton
                    switch app.SelectDatasetButtonGroup.SelectedObject
                        case app.SWaTButton
                            
                        case app.AVSButton
                            
                        case app.UAVButton
                            
                    end
            end
            endTask(app, app.DataPreparationPanel, oldP);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            resetApp(app);
        end

        % Callback function
        function VisualizedataButtonPushed(app, event)
            oldP = beginTask(app, app.PreprocessingPanel_Old);
            if app.SelectDatasetButtonGroup.SelectedObject == app.SWaTButton
                plot(datetime(app.faultFreeData{7*60*60+60*30:end,1}),app.faultFreeData{7*60*60+60*30:end,3})
            end
            endTask(app, app.PreprocessingPanel_Old, oldP);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            global indices
            try
                removeStyle(app.UITable,2)
            catch ME
                
            end
            indices = event.Indices;
            idx = indices(1,2);
            if ismember(idx, [3 20 29 30])
                app.SelectedSensorCheckBox.Value = 1;
                app.VisualizedataButton_2.Enable = 1;
                app.NextButton_3.Enable = 1;
                app.SelectedSensorLampLabel.Text = app.UITable.ColumnName(idx);
                app.SelectedSensorLamp.Color = 'green';
                app.SelectedSensorCheckBox.Text = app.UITable.ColumnName(idx);
                s = uistyle('BackgroundColor','green');
                addStyle(app.UITable,s,'column', idx)
            else
                app.SelectedSensorCheckBox.Value = 0;
                app.VisualizedataButton_2.Enable = 0;
                app.NextButton_3.Enable = 0;
                app.SelectedSensorLampLabel.Text = app.UITable.ColumnName(idx);
                app.SelectedSensorLamp.Color = 'red';
                app.SelectedSensorCheckBox.Text = app.UITable.ColumnName(idx);
                s = uistyle('BackgroundColor','red');
                addStyle(app.UITable,s,'column', idx)
            end
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            resetApp(app);
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            global indices
            oldP = beginTask(app, app.SWaTDataPanel);
            beg = 7*60*60+60*30;
            endI = beg + 60*60*18;
            app.faultFreeData = app.UITable.Data{beg:endI,indices(1,2)};
            openAndResizeWindow(app, app.SWaTDataPanel, app.PreprocessingPanel_Old)
            endTask(app, app.PreprocessingPanel_Old, oldP);
        end

        % Button pushed function: VisualizedataButton_2
        function VisualizedataButton_2Pushed(app, event)
            global indices
            oldP = beginTask(app, app.SWaTDataPanel);
            if app.SelectedSensorCheckBox.Value == 1
                fig = uifigure;
                name = get(app.SelectedSensorCheckBox, 'Text');
                name = "Sensor: "+char(name);
                set(fig, 'Name', name)
                ax = uiaxes(fig,'Position',[10 10 550 400]);
                x = datetime(app.UITable.Data{7*60*60+60*30:end,1});
                y = app.UITable.Data{7*60*60+60*30:end,indices(1,2)};
                plot(ax,x,y)
                lim1 = datetime(app.UITable.Data{7*60*60+60*30,1});
                lim2 = datetime(app.UITable.Data{end,1});
                xlim(ax, [lim1 lim2])
            end
            endTask(app, app.SWaTDataPanel, oldP);
        end

        % Value changed function: NetworkspecificityDropDown
        function NetworkspecificityDropDownValueChanged(app, event)
            value = app.NetworkspecificityDropDown.Value;
            if strcmp(value, 'Basic')
                app.FilterNumberEditField.Visible = 0;
                app.FilterNumberEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Visible = 0;
                app.HiddenUnitsEditFieldLabel.Visible = 0;
                app.NumberofneuronsEditField.Visible = 1;
                app.NumberofneuronsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Value = 32;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            elseif strcmp(value, 'LSTM')
                app.FilterNumberEditField.Visible = 0;
                app.FilterNumberEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Visible = 1;
                app.HiddenUnitsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Visible = 0;
                app.NumberofneuronsEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Value = 80;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            elseif strcmp(value, 'Hybrid (RNN-CNN)')
                app.FilterNumberEditField.Visible = 1;
                app.FilterNumberEditFieldLabel.Visible = 1;
                app.HiddenUnitsEditField.Visible = 1;
                app.HiddenUnitsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Visible = 0;
                app.NumberofneuronsEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Value = 50;
                app.FilterNumberEditField.Value = 32;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            else
                app.FilterNumberEditField.Visible = 0;
                app.FilterNumberEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Visible = 1;
                app.HiddenUnitsEditFieldLabel.Visible = 1;
                app.NumberofneuronsEditField.Visible = 0;
                app.NumberofneuronsEditFieldLabel.Visible = 0;
                app.HiddenUnitsEditField.Value = 80;
                app.EpochsEditField.Value = 35;
                app.MinibatchsizeEditField.Value = 70;
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            oldP = beginTask(app, app.PreprocessingPanel_Old);
            preprocData(app)
            switch app.NetworkButtonGroup_2.SelectedObject
                case app.ReconstructionButton
                    openAndResizeWindow(app, app.PreprocessingPanel_Old, app.NetworkOptionsPanel)
                case app.PredictiveButton
                    app.NetworkspecificityDropDown.Items{1} = 'GRU';
                    app.FilterNumberEditField.Visible = 0;
                    app.FilterNumberEditFieldLabel.Visible = 0;
                    app.HiddenUnitsEditField.Visible = 1;
                    app.HiddenUnitsEditFieldLabel.Visible = 1;
                    app.NumberofneuronsEditField.Visible = 0;
                    app.NumberofneuronsEditFieldLabel.Visible = 0;
                    app.HiddenUnitsEditField.Value = 80;
                    app.EpochsEditField.Value = 35;
                    app.MinibatchsizeEditField.Value = 70;
                    openAndResizeWindow(app, app.PreprocessingPanel_Old, app.NetworkOptionsPanel)
            end
            drawnow
            endTask(app, app.PreprocessingPanel_Old, oldP);
        end

        % Button pushed function: RestartButton_2
        function RestartButton_2Pushed(app, event)
            resetApp(app)
        end

        % Button pushed function: NetworkdesciptionButton
        function NetworkdesciptionButtonPushed(app, event)
            oldP = beginTask(app, app.NetworkOptionsPanel);
            delete(app.NetworkDescApp)
            lay = createGraph(app);
            app.netDescription = generateDesc(app);
            app.NetworkDescApp = netDescApp(app, lay, app.netDescription);
            endTask(app, app.NetworkOptionsPanel, oldP);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            oldP = beginTask(app, app.NetworkOptionsPanel);
            %train on non anomalous data
            [app.dataXTrain,app.dataYTrain] = splitDataEnd(app);            
            splitTrainVal(app)
            switch app.NetworkButtonGroup_2.SelectedObject
                case app.ReconstructionButton
                    switch app.NetworkspecificityDropDown.Value
                        case app.NetworkspecificityDropDown.Items{2} %LSTM
                            trainLSTM_AE(app);
                        case app.NetworkspecificityDropDown.Items{1} %Shallow
                            trainShallow_AE(app);
                        case app.NetworkspecificityDropDown.Items{3} %Hybrid
                            trainHy_AE(app);
                    end
                case app.PredictiveButton
                    switch app.NetworkspecificityDropDown.Value
                        case app.NetworkspecificityDropDown.Items{1} %GRU
                            trainLSTM(app);
                        case app.NetworkspecificityDropDown.Items{2} %LSTM
                            trainRNN(app);
                        case app.NetworkspecificityDropDown.Items{3} %Hybrid
                            trainHy(app)
                    end
            end
            if ~isempty(app.net)
                app.NextButton_4.Enable = 1;
            end
            assignin("base","net" ,app.net)
            %preproc ans split val anom data
            switch app.SelectDatasetButtonGroup.SelectedObject
                case app.AVSButton
                    if size(app.anomValData, 1) == 1
                        switch app.PreprocessingmethodButtonGroup.SelectedObject
                            case app.RawdataButton
                                
                            case app.Rescale01Button
                                app.anomValData{1,1} = (app.anomValData{1,1}-app.mini)/(app.maxi-app.mini);
                            case app.StandardizeButton
                                app.anomValData{1,1} = (app.anomValData{1,1}-app.mu)/app.sig;
                        end
                        [XValAnom, YValAnom] = splitData(app, app.anomValData{1,1});
                        [~, anomLabels] = splitData(app, app.anomValData{1,2});
                        anomLabels = logical(anomLabels);
                    else
                        for i = 1:size(app.anomValData,1)
                            switch app.PreprocessingmethodButtonGroup.SelectedObject
                                case app.RawdataButton
                                    app.anomValData{i,1} = app.anomValData{i,1};
                                case app.Rescale01Button
                                    app.anomValData{i,1} = (app.anomValData{i,1}-app.mini)/(app.maxi-app.mini);
                                case app.StandardizeButton
                                    app.anomValData{i,1} = (app.anomValData{i,1}-app.mu)/app.sig;
                            end
                        end
                        XValAnom = cell(0,1);
                        YValAnom = [];
                        anomLabels = [];
                        for i = 1:size(app.anomValData,1)
                            [A, B] = splitData(app, app.anomValData{i,1});
                            [~, C] = splitData(app, app.anomValData{i,2});
                            XValAnom = [XValAnom; A];
                            YValAnom = [YValAnom; B];
                            anomLabels = [anomLabels; C];
                        end
                        anomLabels = logical(anomLabels);
                    end
                    anomPred = predict(app.net, XValAnom);
                    if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                        resAnom = abs(anomPred-YValAnom);
                    else
                        anomPred = reshapeAutoencPred(app, anomPred);
                        YValAnom = YValAnom(1:end-app.WindowsizewEditField.Value);
                        anomLabels = anomLabels(1:end-app.WindowsizewEditField.Value);
                        resAnom = abs(anomPred-YValAnom);
                    end
                    app.staticThreshold = findStaticThresh(app, resAnom, anomLabels);
                case app.UAVButton
                    fn = fieldnames(app.anomValData);
                    for k = 1:numel(fn)
                        if size(app.anomValData.(fn{k}), 1) == 1
                            switch app.PreprocessingmethodButtonGroup.SelectedObject
                                case app.RawdataButton
                                    
                                case app.Rescale01Button
                                    app.anomValData.(fn{k}){1,1} = (app.anomValData.(fn{k}){1,1}-app.mini)/(app.maxi-app.mini);
                                case app.StandardizeButton
                                    app.anomValData.(fn{k}){1,1} = (app.anomValData.(fn{k}){1,1}-app.mu)/app.sig;
                            end
                            [XValAnom, YValAnom] = splitData(app, app.anomValData.(fn{k}){1,1});
                            [~, anomLabels] = splitData(app, app.anomValData.(fn{k}){1,2});
                            anomLabels = logical(anomLabels);
                        else
                            for i = 1:size(app.anomValData,1)
                                switch app.PreprocessingmethodButtonGroup.SelectedObject
                                    case app.RawdataButton
                                        app.anomValData.(fn{k}){i,1} = app.anomValData.(fn{k}){i,1};
                                    case app.Rescale01Button
                                        app.anomValData.(fn{k}){i,1} = (app.anomValData.(fn{k}){i,1}-app.mini)/(app.maxi-app.mini);
                                    case app.StandardizeButton
                                        app.anomValData.(fn{k}){i,1} = (app.anomValData.(fn{k}){i,1}-app.mu)/app.sig;
                                end
                            end
                            XValAnom = cell(0,1);
                            YValAnom = [];
                            anoms = [];
                            for i = 1:size(app.anomValData,1)
                                [A, B] = splitData(app, app.anomValData.(fn{k}){i,1});
                                [~, C] = splitData(app, app.anomValData.(fn{k}){i,2});
                                XValAnom = [XValAnom; A];
                                YValAnom = [YValAnom; B];
                                anoms = [anoms; C];
                            end
                            anomLabels.(fn{k}) = logical(anoms);
                        end
                        assignin("base", "Xanom", XValAnom)
                        assignin("base", "Yanom", YValAnom)
                        anomPred = predict(app.net, XValAnom);
                        if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                            resAnom = abs(anomPred-YValAnom);
                        else
                            anomPred = reshapeAutoencPred(app, anomPred);
                            YValAnom = YValAnom(1:end-app.WindowsizewEditField.Value);
                            anomLabels.(fn{k}) = anomLabels.(fn{k})(1:end-app.WindowsizewEditField.Value);
                            resAnom = abs(anomPred-YValAnom);
                        end
                        app.staticThreshold.(fn{k}) = findStaticThresh(app, resAnom, anomLabels.(fn{k}));
                    end
                case app.SWaTButton
                    if app.RatioEditField.Enable == 1
                        anomPred = predict(app.net, app.XVal);
                        if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                            resAnom = abs(anomPred-app.YVal);
                        else
                            anomPred = reshapeAutoencPred(app, anomPred);
                            app.YVal = app.YVal(1:end-app.WindowsizewEditField.Value);
                            resAnom = abs(anomPred-app.YVal);
                        end
                        app.staticThreshold = mean(resAnom)+4*std(resAnom);
                    else
                        anomPred = predict(app.net, app.XTrain);
                        if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                            resAnom = abs(anomPred-app.YTrain);
                        else
                            anomPred = reshapeAutoencPred(app, anomPred);
                            app.YTrain = app.YTrain(1:end-app.WindowsizewEditField.Value);
                            resAnom = abs(anomPred-app.YTrain);
                        end
                        app.staticThreshold = mean(resAnom)+4*std(resAnom);
                    end
                    
            end
            assignin("base", "staticThresh", app.staticThreshold)
            %prepare non anomalous training data
            % %             app.trainPredict = predict(app.net, app.XTrain);
            % %             if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
            % %                 res = abs(app.trainPredict-app.YTrain);
            % %                 labels = false(1,length(res));
            % %             else
            % %                 app.trainPredict = reshapeAutoencPred(app, app.trainPredict);
            % %                 YTrain = app.YTrain(1:end-app.WindowsizewEditField.Value);
            % %                 labels = false(1,length(YTrain));
            % %                 res = abs(app.trainPredict-YTrain);
            % %             end
            
            %find best threshold
            % %             app.staticThreshold = findStaticThresh(app, resAnom, anomLabels);
            % %             beta = 1;
            % %             idx = [];
            % %             threshMax = linspace(min(resAnom),max(resAnom), 200);
            % %             y = logical([]);
            % %             if size(anomLabels, 2) ~= 1
            % %                 anomLabels = anomLabels';
            % %             end
            % %             for i = 1:200
            % %                 y(:,i) = resAnom > threshMax(i);
            % %             end
            % %
            % %             precision = []; recall = []; Fscore = [];
            % %
            % %             for i = 1:200
            % %                 k = confusionmat(anomLabels, y(:,i));
            % %                 if width(k) > 1
            % %                     precision(i) = k(2,2)/(k(2,2)+k(1,2));
            % %                     recall(i) = k(2,2)/(k(2,2)+k(2,1));
            % %                     Fscore(i) = (1+beta^2)*(precision(i)*recall(i))/(precision(i)*beta^2+recall(i));
            % %                 end
            % %             end
            % %             MaxFScore = max(Fscore);
            % %             thrIdx = find(Fscore == MaxFScore);
            % %             clear thrMax
            % %             if size(thrIdx, 2) >1
            % %                 p = thrIdx(1);
            % %                 thrMax = threshMax(thrIdx(end));
            % %             else
            % %                 p = thrIdx;
            % %             end
            % %
            % %             app.staticThreshold = threshMax(p);
            
            endTask(app, app.NetworkOptionsPanel, oldP)
        end

        % Button pushed function: SavenettoworkspaceButton
        function SavenettoworkspaceButtonPushed(app, event)
            if ~isempty(app.net)
                app.NetworkOptionsPanel.Enable = 'off';
                app.saveNetPopupWin = saveNetPopup(app);
                waitfor(app.saveNetPopupWin)
                app.NetworkOptionsPanel.Enable = 'on';
            else
                uialert(app.UIFigure,'There is still no trained network to save','Invalid File');
            end
        end

        % Value changed function: UsevaldataCheckBox
        function UsevaldataCheckBoxValueChanged(app, event)
            value = app.UsevaldataCheckBox.Value;
            if value == 1
                app.RatioEditField.Enable = 1;
                app.RatioEditField_2Label.Enable = 1;
            else
                app.RatioEditField.Enable = 0;
                app.RatioEditField_2Label.Enable = 0;
            end
        end

        % Button pushed function: VisualizedataButton_3
        function VisualizedataButton_3Pushed(app, event)
            oldP = beginTask(app, app.AVSPreparationPanel);
            appTitle = "AVS Data";
            switch app.VehiculescenarioButtonGroup.SelectedObject
                case app.TownButton
                    name = "Town scenario data";
                    y = app.AVS.faultFree(13:end, 1);
                    plotData(app, y, name, appTitle)
                case app.HighwayButton
                    name = "Town scenario data";
                    y = app.AVS.faultFree(1:12, 1);
                    plotData(app, y, name, appTitle)
            end
            endTask(app, app.AVSPreparationPanel, oldP);
        end

        % Button pushed function: RestartButton_3
        function RestartButton_3Pushed(app, event)
            resetApp(app)
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
            oldP = beginTask(app, app.AVSPreparationPanel);
            switch app.VehiculescenarioButtonGroup.SelectedObject
                case app.TownButton
                    app.faultFreeData = app.AVS.faultFree(13:end,1);
                    app.AVSType = 13:24;
                    
                case app.HighwayButton
                    app.faultFreeData = app.AVS.faultFree(1:12, 1);
                    app.AVSType = 1:12;
            end
            faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
            %             data = [app.AVS.dataNoise(faultIdx,:); app.AVS.dataOffset(faultIdx,:)];
            %             [m, n] = size(data);
            %             idx = randperm(m);
            %             app.anomValData = data(idx(1:round(0.2*m)),:);
            %%
            data1 = [app.AVS.dataNoise(1:8:end,:); app.AVS.dataNoise(2:8:end,:); app.AVS.dataNoise(3:8:end,:); app.AVS.dataNoise(4:8:end,:)];
            data2 = [app.AVS.dataOffset(faultIdx,:)];
            [m, n] = size(data2);
            idx = randperm(m);
            data2 = data2(idx,:);
            data = [data1;data2];
            [m, n] = size(data);
            idx = randperm(m);
            %%
            app.anomValData = data(idx(1:round(0.2*m)),:);
            openAndResizeWindow(app, app.AVSPreparationPanel, app.PreprocessingPanel_Old)
            endTask(app, app.AVSPreparationPanel, oldP);
            % exportapp(app.UIFigure, 'C:\Users\Alia\Desktop\report\Figures\APP\preproc.png')
        end

        % Button pushed function: RestartButton_4
        function RestartButton_4Pushed(app, event)
            resetApp(app)
        end

        % Button pushed function: NextButton_6
        function NextButton_6Pushed(app, event)
            oldP = beginTask(app, app.UAVPreparationPanel);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    app.faultFreeData = app.UAVAcc.faultFreeAcc;
                    for i = 1:size(app.faultFreeData,1)
                        app.faultFreeData{i,1} = downsample(app.faultFreeData{i,1},16);
                    end
                case app.GyroscopeButton
                    app.faultFreeData = app.UAVGyr.faultFreeGyr;
            end
            openAndResizeWindow(app, app.UAVPreparationPanel, app.PreprocessingPanel_Old)
            app.UAVGyrFault = app.UAVGyrFault.faultyGyr;
            app.UAVAccFault = app.UAVAccFault.faultyAcc;
            if app.SensordataselectionButtonGroup.SelectedObject == app.AccelerometerButton
                downsampleData(app)
                app.anomValData.Offset = randomAnomData(app, app.UAVAccFault.Offset, 0.2);
                app.anomValData.PackageDrop = randomAnomData(app, app.UAVAccFault.PackageDrop, 0.2);
                app.anomValData.StuckAt = randomAnomData(app, app.UAVAccFault.StuckAt, 0.2);
                %                 app.anomValData.Offset = app.UAVAccFault.Offset;
                %                 app.anomValData.PackageDrop = app.UAVAccFault.PackageDrop;
                %                 app.anomValData.StuckAt = app.UAVAccFault.StuckAt;
            else
                app.anomValData.Offset = randomAnomData(app, app.UAVGyrFault.Offset, 0.2);
                app.anomValData.Noise = randomAnomData(app, app.UAVGyrFault.Noise, 0.2);
                app.anomValData.StuckAt = randomAnomData(app, app.UAVGyrFault.StuckAt, 0.2);
            end
            endTask(app, app.UAVPreparationPanel, oldP);
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            oldP = beginTask(app, app.NetworkOptionsPanel);
            switch app.SelectDatasetButtonGroup.SelectedObject
                case app.AVSButton
                    openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_AVS)
                case app.UAVButton
                    switch app.SensordataselectionButtonGroup.SelectedObject
                        case app.AccelerometerButton
                            openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_UAVAcc)
                            app.ScenarioListBox_2.Items = app.ScenarioListBox_2.Items(1:10);
                            app.ScenarioListBox_2.ItemsData = app.ScenarioListBox_2.ItemsData(1:10);
                        case app.GyroscopeButton
                            openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_UAVAcc)
                            app.FaulttypeButtonGroup_4.Visible = 'on';
                            app.FaulttypeButtonGroup_2.Visible = 'off';
                    end
                case app.SWaTButton
                    openAndResizeWindow(app, app.NetworkOptionsPanel, app.FaultydataselectionPanel_SWaT);
            end
            
            endTask(app, app.NetworkOptionsPanel, oldP);
        end

        % Button pushed function: VisualizedataButton_5
        function VisualizedataButton_5Pushed(app, event)
            %             oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
            faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
            dataCleanIdx = str2double(app.ScenarioListBox.Value);
            switch app.FaulttypeButtonGroup.SelectedObject
                case app.StuckvalueButton
                    faultType = app.AVS.dataStuckAt(faultIdx,:);
                case app.NoiseButton
                    faultType = app.AVS.dataNoise(faultIdx,:);
                case app.OffsetButton
                    faultType = app.AVS.dataOffset(faultIdx,:);
            end
            IDX = str2double(app.FaultdurationDropDown.Value);
            dataIdx = 8*(dataCleanIdx-1)+IDX;
            faultData = faultType{dataIdx,1};
            f = uifigure;
            f.Visible = 'off';
            ax = uiaxes(f);
            f.Position(3) = ax.Position(3)+10;
            f.Position(4) = ax.Position(4)+10;
            labels = logical(faultType{dataIdx,2});
            t = 1:length(labels);
            plot(ax, t, faultData)
            patchAnomalies(app, ax, labels)
            legend(ax, 'data', "Anomalies", "Location","bestoutside")
            movegui(f, "center")
            f.Visible = 'on';
            %             endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Button pushed function: VisualizedataButton_4
        function VisualizedataButton_4Pushed(app, event)
            oldP = beginTask(app, app.UAVPreparationPanel);
            appTitle = "UAV Data";
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.GyroscopeButton
                    name = "Gyroscope data";
                    y = app.UAVGyr.faultFreeGyr;
                    plotData(app, y, name, appTitle);
                case app.AccelerometerButton
                    name = "Accuator data";
                    y = app.UAVAcc.faultFreeAcc;
                    plotData(app, y, name, appTitle);
            end
            endTask(app, app.UAVPreparationPanel, oldP);
        end

        % Button pushed function: PredictdataButton
        function PredictdataButtonPushed(app, event)
            %oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
            faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
            dataCleanIdx = str2double(app.ScenarioListBox.Value);
            switch app.FaulttypeButtonGroup.SelectedObject
                case app.StuckvalueButton
                    faultType = app.AVS.dataStuckAt(faultIdx,:);
                case app.NoiseButton
                    faultType = app.AVS.dataNoise(faultIdx,:);
                case app.OffsetButton
                    faultType = app.AVS.dataOffset(faultIdx,:);
            end
            IDX = str2double(app.FaultdurationDropDown.Value);
            dataIdx = 8*(dataCleanIdx-1)+IDX;
            faultData = faultType{dataIdx,1};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            [XTest, YTest] = splitData(app, dataTest);
            pred = predict(app.net, XTest);
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end));
            else
                c = [];
                for i = 1:size(pred,1)
                    c(:,i) = pred{i};
                end
                b = [];
                c = flip(c);
                for i = 1:size(c,2)-app.WindowsizewEditField.Value
                    b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
                end
                pred = b;
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end-app.WindowsizewEditField.Value));
                YTest = YTest(1:end-app.WindowsizewEditField.Value);
            end
            if isempty(app.pd)
                prodDensity = 0;
            else
                prodDensity = app.pd;
            end
            
            plotResultsAndEval(app, pred(1:end-1), YTest(1:end-1), labels(1:end-1), 100, app.staticThreshold, prodDensity);
            %endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Button pushed function: PredictdataButton_2
        function PredictdataButton_2Pushed(app, event)
            %             oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
            dataIdx = str2double(app.ScenarioListBox_2.Value);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    switch app.FaulttypeButtonGroup_2.SelectedObject
                        case app.StuckvalueButton_2
                            faultType = app.UAVAccFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.PackagedropButton
                            faultType = app.UAVAccFault.PackageDrop;
                            threshold = app.staticThreshold.PackageDrop;
                        case app.OffsetButton_2
                            faultType = app.UAVAccFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
                case app.GyroscopeButton
                    switch app.FaulttypeButtonGroup_4.SelectedObject
                        case app.StuckvalueButton_3
                            faultType = app.UAVGyrFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.NoiseButton_3
                            faultType = app.UAVGyrFault.Noise;
                            threshold = app.staticThreshold.Noise;
%                             threshold = app.staticThreshold.Offset;
                        case app.OffsetButton_4
                            faultType = app.UAVGyrFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
            end
            
            faultData = faultType{dataIdx,1};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            [XTest, YTest] = splitData(app, dataTest);
            pred = predict(app.net, XTest);
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end));
            else
                c = [];
                for i = 1:size(pred,1)
                    c(:,i) = pred{i};
                end
                b = [];
                c = flip(c);
                for i = 1:size(c,2)-app.WindowsizewEditField.Value
                    b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
                end
                pred = b;
                labels = logical(faultType{dataIdx, 2}(app.WindowsizewEditField.Value+1:end-app.WindowsizewEditField.Value));
                YTest = YTest(1:end-app.WindowsizewEditField.Value);
            end
            
            if isempty(app.pd)
                prodDensity = 0;
            else
                prodDensity = app.pd;
            end
            if isstruct(threshold)
                threshold = struct2array(threshold);
            end
            
            plotResultsAndEval(app, pred(1:end-1), YTest(1:end-1), labels(1:end-1), 250, threshold, prodDensity);
            
            
            %             endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Button pushed function: OnlinedetectionsimulationButton
        function OnlinedetectionsimulationButtonPushed(app, event)
            
                %             oldP = beginTask(app, app.FaultydataselectionPanel_AVS);
                faultIdx = min(app.AVSType)*8-7:max(app.AVSType)*8;
                dataCleanIdx = str2double(app.ScenarioListBox.Value);
                switch app.FaulttypeButtonGroup.SelectedObject
                    case app.StuckvalueButton
                        faultType = app.AVS.dataStuckAt(faultIdx,:);
                    case app.NoiseButton
                        faultType = app.AVS.dataNoise(faultIdx,:);
                    case app.OffsetButton
                        faultType = app.AVS.dataOffset(faultIdx,:);
                end
                IDX = str2double(app.FaultdurationDropDown.Value);
                dataIdx = 8*(dataCleanIdx-1)+IDX;
                faultData = faultType{dataIdx,1};
                switch app.PreprocessingmethodButtonGroup.SelectedObject
                    case app.RawdataButton
                        dataTest = faultData;
                    case app.Rescale01Button
                        dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                    case app.StandardizeButton
                        dataTest = (faultData-app.mu)/app.sig;
                end
                labels = logical(faultType{dataIdx, 2});
                if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                    method = 'Predictive';
                else
                    method = 'Reconstruction';
                end
                simulinkModel(app, method, app.net, dataTest, labels, app.staticThreshold);
            
            
            %             endTask(app, app.FaultydataselectionPanel_AVS, oldP);
        end

        % Selection changed function: FaulttypeButtonGroup_4
        function FaulttypeButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.FaulttypeButtonGroup_4.SelectedObject;
        end

        % Button pushed function: PredictdataButton_3
        function PredictdataButton_3Pushed(app, event)
            global indices
            %             oldP = beginTask(app, app.FaultydataselectionPanel_SWaT);
            day = str2double(app.DayListBox.Value);
            faultData = app.SWaT.SWaTDatasetAttackv0{1+(day-1)*60*60*24:min(day*60*60*24, end),indices(1,2)};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            [XTest, YTest] = splitData(app, dataTest);
            pred = predict(app.net, XTest);
            %             labs = app.SWaT.SWaTDatasetAttackv0.NormalAttack(1+(day-1)*60*60*24:day*60*60*24);
            %             labels = false(length(labs),1);
            %             labels(labs == "Attack") = true;
            attackList = app.SWaTAttacks.attackList;
            text = app.UITable.ColumnName(indices(1,2));
            idx = find(contains(attackList.AttackPoint, text{:}));
            timebegin = attackList.StartTime(idx);
            timeend = attackList.EndTime(idx);
            labels = false(length(YTest),1);
            time = datetime(app.SWaT.SWaTDatasetAttackv0.Timestamp(1+(day-1)*60*60*24:min(day*60*60*24, end)));
            for i = 1:length(timebegin)
                labels(find(time==timebegin(i)):find(time==timeend(i))) = true;
            end
            assignin("base", "labels", labels)
            assignin("base", "attacks", attackList.AttackPoint)
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                labels = logical(labels(app.WindowsizewEditField.Value+1:end));
            else
                c = [];
                for i = 1:size(pred,1)
                    c(:,i) = pred{i};
                end
                b = [];
                c = flip(c);
                for i = 1:size(c,2)-app.WindowsizewEditField.Value
                    b(i,:) = median(diag(c(:,i:i+app.WindowsizewEditField.Value-1)));
                end
                pred = b;
                labels = logical(labels(app.WindowsizewEditField.Value+1:end-app.WindowsizewEditField.Value));
                YTest = YTest(1:end-app.WindowsizewEditField.Value);
            end
            probDens = app.pd;
            plotResultsAndEval(app, pred, YTest, labels, 1500, app.staticThreshold, probDens);
            %             endTask(app, app.FaultydataselectionPanel_SWaT, oldP);
        end

        % Button pushed function: OnlinedetectionsimulationButton_3
        function OnlinedetectionsimulationButton_3Pushed(app, event)
            dataIdx = str2double(app.ScenarioListBox_2.Value);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    switch app.FaulttypeButtonGroup_2.SelectedObject
                        case app.StuckvalueButton_2
                            faultType = app.UAVAccFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.PackagedropButton
                            faultType = app.UAVAccFault.PackageDrop;
                            threshold = app.staticThreshold.PackageDrop;
                        case app.OffsetButton_2
                            faultType = app.UAVAccFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
                case app.GyroscopeButton
                    switch app.FaulttypeButtonGroup_4.SelectedObject
                        case app.StuckvalueButton_3
                            faultType = app.UAVGyrFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.NoiseButton_3
                            faultType = app.UAVGyrFault.Noise;
                            threshold = app.staticThreshold.Noise;
                        case app.OffsetButton_4
                            faultType = app.UAVGyrFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
            end
            assignin("base", "selected", app.FaulttypeButtonGroup_2.SelectedObject)
            faultData = faultType{dataIdx,1};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
            labels = logical(faultType{dataIdx, 2});
            if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                method = 'Predictive';
            else
                method = 'Reconstruction';
            end
            simulinkModel(app, method, app.net, dataTest, labels, app.staticThreshold)
        end

        % Button pushed function: OnlinedetectionsimulationButton_2
        function OnlinedetectionsimulationButton_2Pushed(app, event)
             global indices
            %             oldP = beginTask(app, app.FaultydataselectionPanel_SWaT);
            day = str2double(app.DayListBox.Value);
            faultData = app.SWaT.SWaTDatasetAttackv0{1+(day-1)*60*60*24:min(day*60*60*24, end),indices(1,2)};
            switch app.PreprocessingmethodButtonGroup.SelectedObject
                case app.RawdataButton
                    dataTest = faultData;
                case app.Rescale01Button
                    dataTest = (faultData-app.mini)/(app.maxi-app.mini);
                case app.StandardizeButton
                    dataTest = (faultData-app.mu)/app.sig;
            end
           
            %             labs = app.SWaT.SWaTDatasetAttackv0.NormalAttack(1+(day-1)*60*60*24:day*60*60*24);
            %             labels = false(length(labs),1);
            %             labels(labs == "Attack") = true;
            attackList = app.SWaTAttacks.attackList;
            text = app.UITable.ColumnName(indices(1,2));
            idx = find(contains(attackList.AttackPoint, text{:}));
            timebegin = attackList.StartTime(idx);
            timeend = attackList.EndTime(idx);
            labels = false(length(dataTest),1);
            time = datetime(app.SWaT.SWaTDatasetAttackv0.Timestamp(1+(day-1)*60*60*24:min(day*60*60*24, end)));
            for i = 1:length(timebegin)
                labels(find(time==timebegin(i)):find(time==timeend(i))) = true;
            end
            
             if app.NetworkButtonGroup_2.SelectedObject == app.PredictiveButton
                method = 'Predictive';
            else
                method = 'Reconstruction';
            end
            simulinkModel(app, method, app.net, dataTest, labels, app.staticThreshold)    
        end

        % Value changed function: ScenarioListBox
        function ScenarioListBoxValueChanged(app, event)
            value = app.ScenarioListBox.Value;
            
        end

        % Button pushed function: VisualizedataButton_7
        function VisualizedataButton_7Pushed(app, event)
            dataIdx = str2double(app.ScenarioListBox_2.Value);
            switch app.SensordataselectionButtonGroup.SelectedObject
                case app.AccelerometerButton
                    switch app.FaulttypeButtonGroup_2.SelectedObject
                        case app.StuckvalueButton_2
                            faultType = app.UAVAccFault.StuckAt;
                            %                             threshold = app.staticThreshold.StuckAt;
                            threshold = app.staticThreshold.Offset;
                        case app.PackagedropButton
                            faultType = app.UAVAccFault.PackageDrop;
                            threshold = app.staticThreshold.PackageDrop;
                        case app.OffsetButton_2
                            faultType = app.UAVAccFault.Offset;
                            threshold = app.staticThreshold.Offset;
                    end
                case app.GyroscopeButton
                    switch app.FaulttypeButtonGroup_4.SelectedObject
                        case app.StuckvalueButton_3
                            faultType = app.UAVGyrFault.StuckAt;
                           
                        case app.NoiseButton_3
                            faultType = app.UAVGyrFault.Noise;
                            
                        case app.OffsetButton_4
                            faultType = app.UAVGyrFault.Offset;
                            
                    end
            end
            faultData = faultType{dataIdx,1};
            f = uifigure;
            f.Visible = 'off';
            ax = uiaxes(f);
            f.Position(3) = ax.Position(3)+10;
            f.Position(4) = ax.Position(4)+10;
            labels = logical(faultType{dataIdx,2});
            t = 1:length(labels);
            plot(ax, t, faultData)
            patchAnomalies(app, ax, labels)
            legend(ax, 'data', "Anomalies", "Location","bestoutside")
            movegui(f, "center")
            f.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.modelSelectionApp)
                app.modelSelectionApp.delete()
            end
            app.delete()
        end

        % Selection change function: MainPanel
        function MainPanelSelectionChanged(app, event)
%             % This is a workaround for disabling tabs since there is no
%             % enable parameter for individual tabs
%             selectedTab = app.MainPanel.SelectedTab;
%             if ~app.loadedTrainingData
%                 app.MainPanel.SelectedTab = event.OldValue;
%             end
        end

        % Selection changed function: D_MainButtonGroup
        function D_MainButtonGroupSelectionChanged(app, event)
            selectedButton = app.D_MainButtonGroup.SelectedObject;
            switch selectedButton
                case app.D_AvailableDatasetsButton
                    app.D_AvailableDatasetsDropDown.Enable = 'on';
                    app.D_AvailableDatasetsDropDownLabel.Enable = 'on';
                    app.D_BrowseCustomDatasetButton.Enable = 'off';
                    app.D_ImportCustomDatasetLabel.Enable = 'off';
                    app.D_ImportCustomDatasetTextArea.Enable = 'off';
                    app.D_ImportCustomDatasetTextArea.Editable = 'off';
                case app.D_CustomDatasetButton
                    app.D_AvailableDatasetsDropDown.Enable = 'off';
                    app.D_AvailableDatasetsDropDownLabel.Enable = 'off';
                    app.D_BrowseCustomDatasetButton.Enable = 'on';
                    app.D_ImportCustomDatasetLabel.Enable = 'on';
                    app.D_ImportCustomDatasetTextArea.Enable = 'on';
                    app.D_ImportCustomDatasetTextArea.Editable = 'on';
            end
            cla(app.D_RawDatasetPreviewUIAxes);
            cla(app.P_PreprocessedDatasetPreviewUIAxes);
            app.showDataPreparationFor('None');
            app.togglePreprocessingTab(false);
            app.toggleModelTrainingTab(false);
            app.toggleDataEvaluationTab(false);
        end

        % Value changed function: D_AvailableDatasetsDropDown
        function D_AvailableDatasetsDropDownValueChanged(app, event)
            cla(app.D_RawDatasetPreviewUIAxes);
            app.showDataPreparationFor('None');
            app.togglePreprocessingTab(false);
            app.toggleModelTrainingTab(false);
            app.toggleDataEvaluationTab(false);
        end

        % Button pushed function: D_BrowseCustomDatasetButton
        function D_BrowseCustomDatasetButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file,path] = uigetfile('*.csv', 'Select csv file with univariate time series data (columns: timestamp, value, is_anomaly).');
            delete(f);
            if ~isequal(file, 0) && ~isequal(path, 0)
                app.D_ImportCustomDatasetTextArea.Value = fullfile(path, file);
            end
        end

        % Button pushed function: D_LoadDataButton
        function D_LoadDataButtonPushed(app, event)
            oldPointer = app.startTask();

            switch app.D_MainButtonGroup.SelectedObject
                case app.D_AvailableDatasetsButton
                    switch app.D_AvailableDatasetsDropDown.Value
                        case 'SWaT'
                            app.SWaTData = load(fullfile(pwd, "myApp_resources\SWaT\SWaT Data.mat"));
                            
                            newItems = strings();
                            for i = 1: length(app.SWaTData.SWaTDatasetNormalv0.Properties.VariableNames)
                                variableName = app.SWaTData.SWaTDatasetNormalv0.Properties.VariableNames{i};
                                if ~strcmp(variableName, 'Timestamp') && ~strcmp(variableName, 'NormalAttack')
                                    newItems(end + 1) = variableName;
                                end
                            end
                            app.D_SWaTSelectSensorDropDown.Items = ['None' newItems(2:end)];

                            app.datasetType = 'SWaT';
                            app.showDataPreparationFor('SWaT');
                        case 'UAV'
                            % Todo: Load UAV data here
                            app.datasetType = 'UAV';
                            app.showDataPreparationFor('UAV');
                        case 'AVS'
                            % Todo: Load AVS data here
                            app.datasetType = 'AVS';
                            app.showDataPreparationFor('AVS');
                    end
                case app.D_CustomDatasetButton
                    customDataTable = app.loadCustomDataset(app.D_ImportCustomDatasetTextArea.Value{1});
                    if ~isequal(customDataTable, 0)
                        % TODO: make data equi-spaced
                        app.rawTrainingData = customDataTable{:,2};
                        app.timestampsTrainingData = customDataTable{:, 1};

                        plot(app.D_RawDatasetPreviewUIAxes, ...
                            datetime(app.timestampsTrainingData), ...
                            app.rawTrainingData);

                        app.datasetType = 'Custom';
                        app.showDataPreparationFor('Custom');

                        app.preprocessedTrainingData = app.rawTrainingData;
                        plot(app.P_PreprocessedDatasetPreviewUIAxes, ...
                            datetime(app.timestampsTrainingData), ...
                            app.preprocessedTrainingData);
                        app.preprocessingMethod = 'Raw Data';
                        app.togglePreprocessingTab(true);
                        app.toggleModelTrainingTab(true);
                    end
            end
            app.stopTask(oldPointer);
        end

        % Button pushed function: D_NextButton
        function D_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Selection changed function: P_MethodButtonGroup
        function P_MethodButtonGroupSelectionChanged(app, event)
            oldPointer = app.startTask();

            selectedButton = app.P_MethodButtonGroup.SelectedObject;
            % TODO: add preprocessing method preview
            switch selectedButton
                case app.P_RescaleButton
                    app.preprocessedTrainingData = app.preprocessData(app.rawTrainingData, 'Rescale');
                    app.preprocessingMethod = 'Rescale';
                case app.P_StandardizeButton
                    app.preprocessedTrainingData = app.preprocessData(app.rawTrainingData, 'Standardize');
                    app.preprocessingMethod = 'Standardize';
                case app.P_RawDataButton
                    app.preprocessedTrainingData = app.rawTrainingData;
                    app.preprocessingMethod = 'Raw';
            end

            plot(app.P_PreprocessedDatasetPreviewUIAxes, ...
                datetime(app.timestampsTrainingData), ...
                app.preprocessedTrainingData);

            app.toggleModelTrainingTab(true);

            app.stopTask(oldPointer);
        end

        % Button pushed function: P_NextButton
        function P_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.ModelTrainingTab;
        end

        % Button pushed function: MT_ViewNetworkButton
        function MT_ViewNetworkButtonPushed(app, event)
            oldPointer = app.startTask();
            
            selectedNetwork = app.MT_NetworksListBox.Value;
            if isempty(selectedNetwork)
                f = errordlg("No network selected for viewing! Select network first.", ...
                        'Network Viewer Error');
                app.stopTask(oldPointer);
                return
            end
            if length(selectedNetwork) > 1
                f = errordlg("Can't disply multiple networks at once! Please select just a single network.", ...
                        'Network Viewer Error');
                app.stopTask(oldPointer);
                return
            end

            graph = app.createModelGraphForPreview(selectedNetwork);
            plot(app.MT_PreviewUIAxes, graph)
            app.MT_PreviewUIAxes.Title.String = sprintf('Network Preview for: %s', selectedNetwork.label);

            app.stopTask(oldPointer);
        end

        % Selection changed function: MT_NetworkSelectionButtonGroup
        function MT_NetworkSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.MT_NetworkSelectionButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MT_NewNetworksButton
                    app.MT_PretrainedNetworkLabel.Enable = 'off';
                    app.MT_PretrainedNetworkTextArea.Enable = 'off';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'off';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'off';
                    app.MT_NetworksLabel.Enable = 'on';
                    app.MT_NetworksListBox.Enable = 'on';
                    app.MT_AddNetworkButton.Enable = 'on';
                case app.MT_PretrainedNetworkButton
                    app.MT_PretrainedNetworkLabel.Enable = 'on';
                    app.MT_PretrainedNetworkTextArea.Enable = 'on';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'on';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'on';
                    app.MT_NetworksLabel.Enable = 'off';
                    app.MT_NetworksListBox.Enable = 'off';
                    app.MT_AddNetworkButton.Enable = 'off';
            end
        end

        % Button pushed function: MT_AddNetworkButton
        function MT_AddNetworkButtonPushed(app, event)
            app.modelSelectionApp = ModelSelection(app);
        end

        % Menu selected function: ShowNetworkOptionsMenu
        function ShowNetworkOptionsMenuSelected(app, event)
            % TODO: Popup that shows the selected network's options
            
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            selectedItems = app.MT_NetworksListBox.Value;
            for i = 1:length(selectedItems)
                [~,idx] = ismember(selectedItems(i).label, app.MT_NetworksListBox.Items);
                app.MT_NetworksListBox.Items(idx) = [];
            end
            if isempty(app.MT_NetworksListBox.Items)
                app.toggleTrainButtons(false)
            end
            app.updateNetworkLabels()
        end

        % Button pushed function: MT_TrainSelectedNetworksButton
        function MT_TrainSelectedNetworksButtonPushed(app, event)
            oldPointer = app.startTask();

            app.trainedNetworks = [];
            
            for i = 1:length(app.MT_NetworksListBox.Value)
                options = app.MT_NetworksListBox.Value(i);
                app.trainedNetworks(i) = app.trainNetwork(options);
            end

            app.stopTask(oldPointer)
        end

        % Button pushed function: MT_TrainAllNetworksButton
        function MT_TrainAllNetworksButtonPushed(app, event)
            oldPointer = app.startTask();

            app.trainedNetworks = [];

            for i = 1:length(app.MT_NetworksListBox.ItemsData)
                options = app.MT_NetworksListBox.ItemsData(i);
                app.trainedNetworks(i) = app.trainNetwork(options);
            end

            app.stopTask(oldPointer)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataPreparationPanel = uipanel(app.UIFigure);
            app.DataPreparationPanel.Title = 'Data Preparation';
            app.SelectDatasetButtonGroup = uibuttongroup(app.DataPreparationPanel);
            app.SelectDatasetButtonGroup.Title = 'Select Dataset';
            app.SWaTButton = uitogglebutton(app.SelectDatasetButtonGroup);
            app.SWaTButton.Text = 'SWaT';
            app.SWaTButton.Value = true;
            app.UAVButton = uitogglebutton(app.SelectDatasetButtonGroup);
            app.UAVButton.Text = 'UAV';
            app.AVSButton = uitogglebutton(app.SelectDatasetButtonGroup);
            app.AVSButton.Text = 'AVS';
            app.NetworkButtonGroup = uibuttongroup(app.DataPreparationPanel);
            app.NetworkButtonGroup.Title = 'Network';
            app.PretrainedButton = uiradiobutton(app.NetworkButtonGroup);
            app.PretrainedButton.Text = 'Pretrained';
            app.PretrainedButton.Value = true;
            app.TrainnewnetworkButton = uiradiobutton(app.NetworkButtonGroup);
            app.TrainnewnetworkButton.Text = 'Train new network';
            app.NextButton = uibutton(app.DataPreparationPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreprocessingPanel_Old = uipanel(app.UIFigure);
            app.PreprocessingPanel_Old.Title = 'Preprocessing';
            app.PreprocessingmethodButtonGroup = uibuttongroup(app.PreprocessingPanel_Old);
            app.PreprocessingmethodButtonGroup.Title = 'Preprocessing method';
            app.StandardizeButton = uitogglebutton(app.PreprocessingmethodButtonGroup);
            app.StandardizeButton.Text = 'Standardize';
            app.StandardizeButton.Value = true;
            app.Rescale01Button = uitogglebutton(app.PreprocessingmethodButtonGroup);
            app.Rescale01Button.Text = 'Rescale [0,1]';
            app.RawdataButton = uitogglebutton(app.PreprocessingmethodButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RestartButton = uibutton(app.PreprocessingPanel_Old, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.NextButton_2 = uibutton(app.PreprocessingPanel_Old, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.NetworkButtonGroup_2 = uibuttongroup(app.PreprocessingPanel_Old);
            app.NetworkButtonGroup_2.Title = 'Network';
            app.PredictiveButton = uiradiobutton(app.NetworkButtonGroup_2);
            app.PredictiveButton.Text = 'Predictive';
            app.PredictiveButton.Value = true;
            app.ReconstructionButton = uiradiobutton(app.NetworkButtonGroup_2);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.SWaTDataPanel = uipanel(app.UIFigure);
            app.SWaTDataPanel.Title = 'SWaT Data';
            app.UITable = uitable(app.SWaTDataPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectedSensorCheckBox = uicheckbox(app.SWaTDataPanel);
            app.SelectedSensorCheckBox.Visible = 'off';
            app.SelectedSensorCheckBox.Text = '''Selected Sensor''';
            app.TextArea = uitextarea(app.SWaTDataPanel);
            app.TextArea.Value = {'Please select a sensor from the table'};
            app.NextButton_3 = uibutton(app.SWaTDataPanel, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Enable = 'off';
            app.NextButton_3.Text = 'Next';
            app.ResetButton_2 = uibutton(app.SWaTDataPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.VisualizedataButton_2 = uibutton(app.SWaTDataPanel, 'push');
            app.VisualizedataButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_2Pushed, true);
            app.VisualizedataButton_2.Enable = 'off';
            app.VisualizedataButton_2.Text = 'Visualize data';
            app.SelectedSensorLampLabel = uilabel(app.SWaTDataPanel);
            app.SelectedSensorLampLabel.Text = '''Selected Sensor''';
            app.SelectedSensorLamp = uilamp(app.SWaTDataPanel);
            app.NetworkOptionsPanel = uipanel(app.UIFigure);
            app.NetworkOptionsPanel.Title = 'Network Options';
            app.Image = uiimage(app.NetworkOptionsPanel);
            app.Image.ImageSource = 'window step.png';
            app.RestartButton_2 = uibutton(app.NetworkOptionsPanel, 'push');
            app.RestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_2Pushed, true);
            app.RestartButton_2.Text = 'Restart';
            app.TrainButton = uibutton(app.NetworkOptionsPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.NetworkspecificityDropDownLabel = uilabel(app.NetworkOptionsPanel);
            app.NetworkspecificityDropDownLabel.Text = 'Network specificity';
            app.NetworkspecificityDropDown = uidropdown(app.NetworkOptionsPanel);
            app.NetworkspecificityDropDown.Items = {'Basic', 'LSTM', 'Hybrid (RNN-CNN)'};
            app.NetworkspecificityDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkspecificityDropDownValueChanged, true);
            app.NetworkspecificityDropDown.Value = 'Basic';
            app.NumberofneuronsEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.NumberofneuronsEditFieldLabel.Text = 'Number of neurons';
            app.NumberofneuronsEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.NumberofneuronsEditField.Tooltip = {'Number for first layer. then n2 = n/2 and n3 = n2/2'};
            app.NumberofneuronsEditField.Value = 32;
            app.HiddenUnitsEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.HiddenUnitsEditFieldLabel.Text = 'Hidden Units';
            app.HiddenUnitsEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.HiddenUnitsEditField.Value = 32;
            app.WindowsizewEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.WindowsizewEditFieldLabel.Text = 'Window size (w)';
            app.WindowsizewEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.WindowsizewEditField.Value = 35;
            app.StepSizesEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.StepSizesEditFieldLabel.Text = 'Step Size (s)';
            app.StepSizesEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.StepSizesEditField.Editable = 'off';
            app.StepSizesEditField.Value = 1;
            app.FilterNumberEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.FilterNumberEditFieldLabel.Text = 'Filter Number';
            app.FilterNumberEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.EpochsEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.EpochsEditField.Value = 35;
            app.MinibatchsizeEditFieldLabel = uilabel(app.NetworkOptionsPanel);
            app.MinibatchsizeEditFieldLabel.Text = 'Minibatch size';
            app.MinibatchsizeEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.MinibatchsizeEditField.Value = 70;
            app.SavenettoworkspaceButton = uibutton(app.NetworkOptionsPanel, 'push');
            app.SavenettoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavenettoworkspaceButtonPushed, true);
            app.SavenettoworkspaceButton.Text = 'Save net to workspace';
            app.UsevaldataCheckBox = uicheckbox(app.NetworkOptionsPanel);
            app.UsevaldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsevaldataCheckBoxValueChanged, true);
            app.UsevaldataCheckBox.Text = 'Use val data';
            app.NetworkdesciptionButton = uibutton(app.NetworkOptionsPanel, 'push');
            app.NetworkdesciptionButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkdesciptionButtonPushed, true);
            app.NetworkdesciptionButton.Text = 'Network desciption';
            app.NextButton_4 = uibutton(app.NetworkOptionsPanel, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Enable = 'off';
            app.NextButton_4.Text = 'Next';
            app.RatioEditField_2Label = uilabel(app.NetworkOptionsPanel);
            app.RatioEditField_2Label.Enable = 'off';
            app.RatioEditField_2Label.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.NetworkOptionsPanel, 'numeric');
            app.RatioEditField.Limits = [0 1];
            app.RatioEditField.Enable = 'off';
            app.RatioEditField.Tooltip = {'Train/Validation ratio'; '0 < value < 1'; 'Recommended: 0.8'};
            app.RatioEditField.Value = 0.8;
            app.AVSPreparationPanel = uipanel(app.UIFigure);
            app.AVSPreparationPanel.Title = 'AVS Preparation';
            app.VehiculescenarioButtonGroup = uibuttongroup(app.AVSPreparationPanel);
            app.VehiculescenarioButtonGroup.Title = 'Vehicule scenario';
            app.HighwayButton = uitogglebutton(app.VehiculescenarioButtonGroup);
            app.HighwayButton.Text = 'Highway';
            app.HighwayButton.Value = true;
            app.TownButton = uitogglebutton(app.VehiculescenarioButtonGroup);
            app.TownButton.Text = 'Town';
            app.RestartButton_3 = uibutton(app.AVSPreparationPanel, 'push');
            app.RestartButton_3.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_3Pushed, true);
            app.RestartButton_3.Text = 'Restart';
            app.NextButton_5 = uibutton(app.AVSPreparationPanel, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next';
            app.VisualizedataButton_3 = uibutton(app.AVSPreparationPanel, 'push');
            app.VisualizedataButton_3.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_3Pushed, true);
            app.VisualizedataButton_3.Text = 'Visualize data';
            app.UAVPreparationPanel = uipanel(app.UIFigure);
            app.UAVPreparationPanel.Title = 'UAV Preparation';
            app.SensordataselectionButtonGroup = uibuttongroup(app.UAVPreparationPanel);
            app.SensordataselectionButtonGroup.Title = 'Sensor data selection';
            app.AccelerometerButton = uitogglebutton(app.SensordataselectionButtonGroup);
            app.AccelerometerButton.Text = 'Accelerometer';
            app.AccelerometerButton.Value = true;
            app.GyroscopeButton = uitogglebutton(app.SensordataselectionButtonGroup);
            app.GyroscopeButton.Text = 'Gyroscope';
            app.RestartButton_4 = uibutton(app.UAVPreparationPanel, 'push');
            app.RestartButton_4.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_4Pushed, true);
            app.RestartButton_4.Text = 'Restart';
            app.NextButton_6 = uibutton(app.UAVPreparationPanel, 'push');
            app.NextButton_6.ButtonPushedFcn = createCallbackFcn(app, @NextButton_6Pushed, true);
            app.NextButton_6.Text = 'Next';
            app.VisualizedataButton_4 = uibutton(app.UAVPreparationPanel, 'push');
            app.VisualizedataButton_4.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_4Pushed, true);
            app.VisualizedataButton_4.Text = 'Visualize data';
            app.FaultydataselectionPanel_AVS = uipanel(app.UIFigure);
            app.FaultydataselectionPanel_AVS.Title = 'Faulty data selection';
            app.FaulttypeButtonGroup = uibuttongroup(app.FaultydataselectionPanel_AVS);
            app.FaulttypeButtonGroup.Title = 'Fault type';
            app.OffsetButton = uitogglebutton(app.FaulttypeButtonGroup);
            app.OffsetButton.Text = 'Offset';
            app.OffsetButton.Value = true;
            app.NoiseButton = uitogglebutton(app.FaulttypeButtonGroup);
            app.NoiseButton.Text = 'Noise';
            app.StuckvalueButton = uitogglebutton(app.FaulttypeButtonGroup);
            app.StuckvalueButton.Text = 'Stuck value';
            app.VisualizedataButton_5 = uibutton(app.FaultydataselectionPanel_AVS, 'push');
            app.VisualizedataButton_5.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_5Pushed, true);
            app.VisualizedataButton_5.Text = 'Visualize data';
            app.FaultdurationDropDownLabel = uilabel(app.FaultydataselectionPanel_AVS);
            app.FaultdurationDropDownLabel.Text = 'Fault duration';
            app.FaultdurationDropDown = uidropdown(app.FaultydataselectionPanel_AVS);
            app.FaultdurationDropDown.Items = {'0.6s', '1:2s', '1.8s', '2.4s', '3.0s', '3.6s', '4.2s', '5.0s'};
            app.FaultdurationDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.FaultdurationDropDown.Value = '1';
            app.ScenarioListBoxLabel = uilabel(app.FaultydataselectionPanel_AVS);
            app.ScenarioListBoxLabel.Text = 'Scenario';
            app.ScenarioListBox = uilistbox(app.FaultydataselectionPanel_AVS);
            app.ScenarioListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ScenarioListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ScenarioListBox.ValueChangedFcn = createCallbackFcn(app, @ScenarioListBoxValueChanged, true);
            app.ScenarioListBox.Value = '1';
            app.PredictdataButton = uibutton(app.FaultydataselectionPanel_AVS, 'push');
            app.PredictdataButton.ButtonPushedFcn = createCallbackFcn(app, @PredictdataButtonPushed, true);
            app.PredictdataButton.Text = 'Predict data';
            app.OnlinedetectionsimulationButton = uibutton(app.FaultydataselectionPanel_AVS, 'push');
            app.OnlinedetectionsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OnlinedetectionsimulationButtonPushed, true);
            app.OnlinedetectionsimulationButton.Text = 'Online detection simulation';
            app.FaultydataselectionPanel_UAVAcc = uipanel(app.UIFigure);
            app.FaultydataselectionPanel_UAVAcc.Title = 'Faulty data selection';
            app.FaulttypeButtonGroup_2 = uibuttongroup(app.FaultydataselectionPanel_UAVAcc);
            app.FaulttypeButtonGroup_2.Title = 'Fault type';
            app.OffsetButton_2 = uitogglebutton(app.FaulttypeButtonGroup_2);
            app.OffsetButton_2.Text = 'Offset';
            app.OffsetButton_2.Value = true;
            app.PackagedropButton = uitogglebutton(app.FaulttypeButtonGroup_2);
            app.PackagedropButton.Text = 'Package drop';
            app.StuckvalueButton_2 = uitogglebutton(app.FaulttypeButtonGroup_2);
            app.StuckvalueButton_2.Text = 'Stuck value';
            app.ScenarioListBox_2Label = uilabel(app.FaultydataselectionPanel_UAVAcc);
            app.ScenarioListBox_2Label.Text = 'Scenario';
            app.ScenarioListBox_2 = uilistbox(app.FaultydataselectionPanel_UAVAcc);
            app.ScenarioListBox_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ScenarioListBox_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ScenarioListBox_2.Value = '1';
            app.PredictdataButton_2 = uibutton(app.FaultydataselectionPanel_UAVAcc, 'push');
            app.PredictdataButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictdataButton_2Pushed, true);
            app.PredictdataButton_2.Text = 'Predict data';
            app.FaulttypeButtonGroup_4 = uibuttongroup(app.FaultydataselectionPanel_UAVAcc);
            app.FaulttypeButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @FaulttypeButtonGroup_4SelectionChanged, true);
            app.FaulttypeButtonGroup_4.Title = 'Fault type';
            app.FaulttypeButtonGroup_4.Visible = 'off';
            app.OffsetButton_4 = uitogglebutton(app.FaulttypeButtonGroup_4);
            app.OffsetButton_4.Text = 'Offset';
            app.OffsetButton_4.Value = true;
            app.NoiseButton_3 = uitogglebutton(app.FaulttypeButtonGroup_4);
            app.NoiseButton_3.Text = 'Noise';
            app.StuckvalueButton_3 = uitogglebutton(app.FaulttypeButtonGroup_4);
            app.StuckvalueButton_3.Text = 'Stuck value';
            app.VisualizedataButton_7 = uibutton(app.FaultydataselectionPanel_UAVAcc, 'push');
            app.VisualizedataButton_7.ButtonPushedFcn = createCallbackFcn(app, @VisualizedataButton_7Pushed, true);
            app.VisualizedataButton_7.Text = 'Visualize data';
            app.OnlinedetectionsimulationButton_3 = uibutton(app.FaultydataselectionPanel_UAVAcc, 'push');
            app.OnlinedetectionsimulationButton_3.ButtonPushedFcn = createCallbackFcn(app, @OnlinedetectionsimulationButton_3Pushed, true);
            app.OnlinedetectionsimulationButton_3.Text = 'Online detection simulation';
            app.FaultydataselectionPanel_SWaT = uipanel(app.UIFigure);
            app.FaultydataselectionPanel_SWaT.Title = 'Faulty data selection';
            app.DayListBoxLabel = uilabel(app.FaultydataselectionPanel_SWaT);
            app.DayListBoxLabel.Text = 'Day';
            app.DayListBox = uilistbox(app.FaultydataselectionPanel_SWaT);
            app.DayListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.DayListBox.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.DayListBox.Value = '1';
            app.PredictdataButton_3 = uibutton(app.FaultydataselectionPanel_SWaT, 'push');
            app.PredictdataButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredictdataButton_3Pushed, true);
            app.PredictdataButton_3.Text = 'Predict data';
            app.VisualizedataButton_6 = uibutton(app.FaultydataselectionPanel_SWaT, 'push');
            app.VisualizedataButton_6.Text = 'Visualize data';
            app.OnlinedetectionsimulationButton_2 = uibutton(app.FaultydataselectionPanel_SWaT, 'push');
            app.OnlinedetectionsimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @OnlinedetectionsimulationButton_2Pushed, true);
            app.OnlinedetectionsimulationButton_2.Text = 'Online detection simulation';
            app.MainPanel = uitabgroup(app.UIFigure);
            app.MainPanel.SelectionChangedFcn = createCallbackFcn(app, @MainPanelSelectionChanged, true);
            app.DataTab = uitab(app.MainPanel);
            app.DataTab.Title = '1. Data';
            app.DataPanel = uipanel(app.DataTab);
            app.D_RawDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawDatasetPreviewUIAxes, 'Preview')
            xlabel(app.D_RawDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawDatasetPreviewUIAxes, 'Z')
            app.D_MainButtonGroup = uibuttongroup(app.DataPanel);
            app.D_MainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @D_MainButtonGroupSelectionChanged, true);
            app.D_MainButtonGroup.Tooltip = {''};
            app.D_MainButtonGroup.Title = 'Data Selection';
            app.D_CustomDatasetButton = uiradiobutton(app.D_MainButtonGroup);
            app.D_CustomDatasetButton.Tooltip = {'Select a .csv file containing these columns:'; 'timestamp, value, is_anomaly (optional)'};
            app.D_CustomDatasetButton.Text = 'Custom Dataset (csv File)';
            app.D_CustomDatasetButton.Value = true;
            app.D_AvailableDatasetsButton = uiradiobutton(app.D_MainButtonGroup);
            app.D_AvailableDatasetsButton.Tooltip = {'Select one of the datasets which are included in the platform by default.'};
            app.D_AvailableDatasetsButton.Text = 'Available Datasets';
            app.D_BrowseCustomDatasetButton = uibutton(app.D_MainButtonGroup, 'push');
            app.D_BrowseCustomDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @D_BrowseCustomDatasetButtonPushed, true);
            app.D_BrowseCustomDatasetButton.Text = 'Browse...';
            app.D_ImportCustomDatasetTextArea = uitextarea(app.D_MainButtonGroup);
            app.D_ImportCustomDatasetLabel = uilabel(app.D_MainButtonGroup);
            app.D_ImportCustomDatasetLabel.Text = 'File:';
            app.D_AvailableDatasetsDropDown = uidropdown(app.D_MainButtonGroup);
            app.D_AvailableDatasetsDropDown.Items = {'SWaT', 'UAV', 'AVS'};
            app.D_AvailableDatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @D_AvailableDatasetsDropDownValueChanged, true);
            app.D_AvailableDatasetsDropDown.Enable = 'off';
            app.D_AvailableDatasetsDropDown.Value = 'SWaT';
            app.D_AvailableDatasetsDropDownLabel = uilabel(app.D_MainButtonGroup);
            app.D_AvailableDatasetsDropDownLabel.Enable = 'off';
            app.D_AvailableDatasetsDropDownLabel.Text = 'Dataset:';
            app.D_LoadDataButton = uibutton(app.DataPanel, 'push');
            app.D_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @D_LoadDataButtonPushed, true);
            app.D_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.D_LoadDataButton.Text = 'Load Data';
            app.D_SWaTSelectSensorLabel = uilabel(app.DataPanel);
            app.D_SWaTSelectSensorLabel.Visible = 'off';
            app.D_SWaTSelectSensorLabel.Text = 'Select sensor:';
            app.D_SWaTSelectSensorDropDown = uidropdown(app.DataPanel);
            app.D_SWaTSelectSensorDropDown.Items = {};
            app.D_SWaTSelectSensorDropDown.Visible = 'off';
            app.D_SWaTSelectSensorDropDown.Value = {};
            app.D_UAVSensorDataButtonGroup = uibuttongroup(app.DataPanel);
            app.D_UAVSensorDataButtonGroup.Title = 'Sensor data selection';
            app.D_UAVSensorDataButtonGroup.Visible = 'off';
            app.D_UAVAccelerometerButton = uiradiobutton(app.D_UAVSensorDataButtonGroup);
            app.D_UAVAccelerometerButton.Text = 'Accelerometer';
            app.D_UAVAccelerometerButton.Value = true;
            app.D_UAVGyroscopeButton = uiradiobutton(app.D_UAVSensorDataButtonGroup);
            app.D_UAVGyroscopeButton.Text = 'Gyroscope';
            app.D_AVSVehicleScenarioButtonGroup = uibuttongroup(app.DataPanel);
            app.D_AVSVehicleScenarioButtonGroup.Title = 'Vehicle scenario';
            app.D_AVSVehicleScenarioButtonGroup.Visible = 'off';
            app.D_AVSHighwayButton = uiradiobutton(app.D_AVSVehicleScenarioButtonGroup);
            app.D_AVSHighwayButton.Text = 'Highway';
            app.D_AVSHighwayButton.Value = true;
            app.D_AVSTownButton = uiradiobutton(app.D_AVSVehicleScenarioButtonGroup);
            app.D_AVSTownButton.Text = 'Town';
            app.D_NextButton = uibutton(app.DataPanel, 'push');
            app.D_NextButton.ButtonPushedFcn = createCallbackFcn(app, @D_NextButtonPushed, true);
            app.D_NextButton.Enable = 'off';
            app.D_NextButton.Text = 'Next';
            app.PreprocessingTab = uitab(app.MainPanel);
            app.PreprocessingTab.Title = '2. Preprocessing';
            app.PreprocessingPanel = uipanel(app.PreprocessingTab);
            app.PreprocessingPanel.Enable = 'off';
            app.P_PreprocessedDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedDatasetPreviewUIAxes, 'Preview')
            xlabel(app.P_PreprocessedDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedDatasetPreviewUIAxes, 'Z')
            app.P_NextButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_NextButton.ButtonPushedFcn = createCallbackFcn(app, @P_NextButtonPushed, true);
            app.P_NextButton.Enable = 'off';
            app.P_NextButton.Text = 'Next';
            app.P_PreviousButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_PreviousButton.Text = 'Previous';
            app.P_MethodButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.P_MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @P_MethodButtonGroupSelectionChanged, true);
            app.P_MethodButtonGroup.Title = 'Preprocessing Method';
            app.P_RawDataButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RawDataButton.Text = 'Raw Data';
            app.P_RawDataButton.Value = true;
            app.P_StandardizeButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_StandardizeButton.Text = 'Standardize';
            app.P_RescaleButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RescaleButton.Text = 'Rescale [0, 1]';
            app.ModelTrainingTab = uitab(app.MainPanel);
            app.ModelTrainingTab.Title = '3. Model Training';
            app.ModelTrainingPanel = uipanel(app.ModelTrainingTab);
            app.MT_PreviewUIAxes = uiaxes(app.ModelTrainingPanel);
            title(app.MT_PreviewUIAxes, 'Network preview')
            app.MT_PreviewUIAxes.XTick = [];
            app.MT_PreviewUIAxes.YTick = [];
            app.MT_PreviewUIAxes.Visible = 'off';
            app.MT_NextButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_NextButton.Enable = 'off';
            app.MT_NextButton.Text = 'Next';
            app.MT_NetworkSelectionButtonGroup = uibuttongroup(app.ModelTrainingPanel);
            app.MT_NetworkSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MT_NetworkSelectionButtonGroupSelectionChanged, true);
            app.MT_NetworkSelectionButtonGroup.Title = 'Network Selection';
            app.MT_NewNetworksButton = uiradiobutton(app.MT_NetworkSelectionButtonGroup);
            app.MT_NewNetworksButton.Text = 'New Networks';
            app.MT_NewNetworksButton.Value = true;
            app.MT_PretrainedNetworkButton = uiradiobutton(app.MT_NetworkSelectionButtonGroup);
            app.MT_PretrainedNetworkButton.Text = 'Pretrained Network';
            app.MT_PretrainedNetworkBrowseButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_PretrainedNetworkBrowseButton.Enable = 'off';
            app.MT_PretrainedNetworkBrowseButton.Text = 'Browse...';
            app.MT_PretrainedNetworkTextArea = uitextarea(app.MT_NetworkSelectionButtonGroup);
            app.MT_PretrainedNetworkTextArea.Enable = 'off';
            app.MT_PretrainedNetworkLabel = uilabel(app.MT_NetworkSelectionButtonGroup);
            app.MT_PretrainedNetworkLabel.Enable = 'off';
            app.MT_PretrainedNetworkLabel.Text = 'File:';
            app.MT_PretrainedNetworkLoadButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_PretrainedNetworkLoadButton.Enable = 'off';
            app.MT_PretrainedNetworkLoadButton.Text = 'Load Net';
            app.MT_NetworksListBox = uilistbox(app.MT_NetworkSelectionButtonGroup);
            app.MT_NetworksListBox.Items = {};
            app.MT_NetworksListBox.Multiselect = 'on';
            app.MT_NetworksListBox.Value = {};
            app.MT_NetworksLabel = uilabel(app.MT_NetworkSelectionButtonGroup);
            app.MT_NetworksLabel.Text = 'Networks';
            app.MT_AddNetworkButton = uibutton(app.MT_NetworkSelectionButtonGroup, 'push');
            app.MT_AddNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @MT_AddNetworkButtonPushed, true);
            app.MT_AddNetworkButton.Text = 'Add';
            app.MT_PreviousButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_PreviousButton.Text = 'Previous';
            app.MT_TrainSelectedNetworksButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainSelectedNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainSelectedNetworksButtonPushed, true);
            app.MT_TrainSelectedNetworksButton.Text = 'Train selected Networks';
            app.TrainingStatusunfinishedLampLabel = uilabel(app.ModelTrainingPanel);
            app.TrainingStatusunfinishedLampLabel.Text = 'Training Status: unfinished';
            app.MT_TrainingStatusInformationLamp = uilamp(app.ModelTrainingPanel);
            app.MT_SaveNetworksButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_SaveNetworksButton.Enable = 'off';
            app.MT_SaveNetworksButton.Text = 'Save Networks';
            app.MT_TrainAllNetworksButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainAllNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainAllNetworksButtonPushed, true);
            app.MT_TrainAllNetworksButton.Text = 'Train all Networks';
            app.MT_ViewNetworkButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_ViewNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @MT_ViewNetworkButtonPushed, true);
            app.MT_ViewNetworkButton.Text = 'View selected Network';
            app.DetectionEvaluationTab = uitab(app.MainPanel);
            app.DetectionEvaluationTab.Title = '4. Detection Evaluation';
            app.DetectionEvaluationPanel = uipanel(app.DetectionEvaluationTab);
            app.DetectionEvaluationPanel.Enable = 'off';
            app.selectedModelsContextMenu = uicontextmenu(app.UIFigure);
            app.ShowNetworkOptionsMenu = uimenu(app.selectedModelsContextMenu);
            app.ShowNetworkOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowNetworkOptionsMenuSelected, true);
            app.ShowNetworkOptionsMenu.Text = 'Show network options';
            app.DeleteMenu = uimenu(app.selectedModelsContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.selectedModelsContextMenu
            app.MT_NetworksListBox.ContextMenu = app.selectedModelsContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end